classdef Controlling_DC_motor_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        TabGroup                     matlab.ui.container.TabGroup
        ConnectionTab                matlab.ui.container.Tab
        ConfigrationPanel            matlab.ui.container.Panel
        BoardsDropDown               matlab.ui.control.DropDown
        BoardLabel                   matlab.ui.control.Label
        AvailablePortsDropDown       matlab.ui.control.DropDown
        AvailablePortsDropDownLabel  matlab.ui.control.Label
        SelectYourBoardLabel         matlab.ui.control.Label
        SelectYourPortLabel          matlab.ui.control.Label
        TestingPanel                 matlab.ui.container.Panel
        TestBlinkD13Button           matlab.ui.control.Button
        RefreshButton                matlab.ui.control.Button
        ConnectionPanel              matlab.ui.container.Panel
        DisconnectButton             matlab.ui.control.Button
        ConnectButton                matlab.ui.control.Button
        StatusPanel                  matlab.ui.container.Panel
        StatusLamp                   matlab.ui.control.Lamp
        StatusLampLabel              matlab.ui.control.Label
        StatusNotconnectedLabel      matlab.ui.control.Label
        MotorOptimizingTab           matlab.ui.container.Tab
        ResultsPanel                 matlab.ui.container.Panel
        UITable                      matlab.ui.control.Table
        UIAxes_2                     matlab.ui.control.UIAxes
        OptimizorSettingsPanel       matlab.ui.container.Panel
        StopButton_2                 matlab.ui.control.Button
        StartButton                  matlab.ui.control.Button
        IterationsSpinner_2          matlab.ui.control.Spinner
        IterationsSpinner_2Label     matlab.ui.control.Label
        NumberofStarsSpinner         matlab.ui.control.Spinner
        NumberofStarsSpinnerLabel    matlab.ui.control.Label
        SetPIDLimitsPanel            matlab.ui.container.Panel
        MaxKpSpinner                 matlab.ui.control.Spinner
        MaxKpSpinnerLabel            matlab.ui.control.Label
        MaxKiSpinner                 matlab.ui.control.Spinner
        MaxKiSpinnerLabel            matlab.ui.control.Label
        MaxKdSpinner                 matlab.ui.control.Spinner
        MaxKdSpinnerLabel            matlab.ui.control.Label
        MinKdSpinner                 matlab.ui.control.Spinner
        MinKdSpinnerLabel            matlab.ui.control.Label
        MinKiSpinner                 matlab.ui.control.Spinner
        MinKiSpinnerLabel            matlab.ui.control.Label
        MinKpSpinner                 matlab.ui.control.Spinner
        MinKpSpinnerLabel            matlab.ui.control.Label
        MotorControlTab              matlab.ui.container.Tab
        SystemMonitoringPanel        matlab.ui.container.Panel
        SaveDataButton               matlab.ui.control.Button
        PWMLabel                     matlab.ui.control.Label
        ErrorLabel                   matlab.ui.control.Label
        ActualRPMLabel               matlab.ui.control.Label
        UIAxes                       matlab.ui.control.UIAxes
        SystemControllingPanel       matlab.ui.container.Panel
        StopButton                   matlab.ui.control.Button
        RunButton                    matlab.ui.control.Button
        DirectionDropDown            matlab.ui.control.DropDown
        DirectionDropDownLabel       matlab.ui.control.Label
        RPMTargetSpinner             matlab.ui.control.Spinner
        RPMTargetSpinnerLabel        matlab.ui.control.Label
        KdSpinner                    matlab.ui.control.Spinner
        KdSpinnerLabel               matlab.ui.control.Label
        KiSpinner                    matlab.ui.control.Spinner
        KiSpinnerLabel               matlab.ui.control.Label
        KpSpinner                    matlab.ui.control.Spinner
        KpSpinnerLabel               matlab.ui.control.Label
    end


    properties (Access = private)

        % ===== Connection / Hardware =====
        hwConnected (1,1) logical = false
        lastCount   (1,1) double  = 0

        a
        enc

        % ===== Serial / Board =====
        COM   = '/dev/ttyUSB0';
        BOARD = 'Nano3';

        % ===== Motor Driver Pins =====
        R_PWM = 'D9';
        L_PWM = 'D10';
        R_EN  = 'D8';
        L_EN  = 'D7';

        R_IS  = 'A0';
        L_IS  = 'A1';

        ENC_A = 'D2';
        ENC_B = 'D3';

        % ===== Encoder / Mechanical =====
        CPR_MOTOR_4X  = 64.0
        GEAR_RATIO    = 131.25
        RPM_AT_OUTPUT = true
        CPR_TOTAL

        % ===== Sampling =====
        SAMPLE_MS = 50
        TsTarget

        % ===== PWM Limits =====
        PWM_MIN = 0
        PWM_MAX = 255

        lastPWM (1,1) double = 0

        % ===== Plot Buffers =====
        bufMax (1,1) double = 600

        tBuf
        targetBuf
        rpmBuf
        errBuf
        pwmBuf

        % ===== Plot Lines =====
        lineTarget
        lineRPM
        lineErr
        linePWM

        % ===== Control Settings =====
        SETPOINT_RPM = 50.0
        forwardDir   = true

        % ===== PID Gains =====
        Kp = 3.169358962773311
        Ki = 1.098408159868921
        Kd = 0.113714386494516

        % ===== Filtering / Control Tuning =====
        rpmAlpha = 0.35      % RPM filter
        dAlpha   = 0.20      % derivative filter
        I_LIMIT  = 120       % anti-windup limit

        % ===== Feedforward Model =====
        PWM_AT_MAX_RPM (1,1) double = 255
        RPM_AT_MAX_PWM (1,1) double = 76
        PWM_DEADZONE   (1,1) double = 35

        PWM_CORRECTION_LIMIT (1,1) double = 90

        % ===== Runtime States =====
        tmr
        Running = false
        stopFlag = false

        t0
        tPrev
        firstSample = true

        integral  = 0.0
        prevError = 0.0
        dFiltered = 0.0
        rpmFilt   = 0.0
        prevRPMFilt (1,1) double = 0


        % ===== PWM Slew Rate =====
        pwmSlewUp   (1,1) double = 600
        pwmSlewDown (1,1) double = 900

        % ===== Save / Plot =====
        saveEndIndex (1,1) double = NaN
        controlPlotCounter (1,1) double = 0

        % ===== Optimization (Black Hole) =====
        Optimizing = false
        optStopFlag = false
        optTestSec (1,1) double = 5

        optLineTarget
        optLineRPM
        optLinePWM

    end

    methods (Access = private)

        function controlStep(app)
            if ~app.hwConnected || isempty(app.a) || isempty(app.enc)
                return;
            end

            if ~app.Running || app.stopFlag || app.Optimizing
                return;
            end

            t = toc(app.t0);

            dtReal = t - app.tPrev;
            app.tPrev = t;

            if dtReal <= 0 || ~isfinite(dtReal)
                return;
            end

            count = readCount(app.enc);
            delta = count - app.lastCount;
            app.lastCount = count;

            rpmRaw = abs((double(delta) * 60.0) / (double(app.CPR_TOTAL) * dtReal));

            dt = app.TsTarget;

            if app.firstSample
                app.rpmFilt = rpmRaw;
                app.prevRPMFilt = rpmRaw;
                app.integral = 0;
                app.prevError = app.SETPOINT_RPM - app.rpmFilt;
                app.dFiltered = 0;
                app.firstSample = false;
            else
                app.rpmFilt = app.rpmAlpha * rpmRaw + (1 - app.rpmAlpha) * app.rpmFilt;
            end

            if app.SETPOINT_RPM < 0.5
                errNow = 0;
                app.integral = 0;
                app.prevError = 0;
                app.dFiltered = 0;
                pwmCmd = 0;
            else
                errNow = app.SETPOINT_RPM - app.rpmFilt;

                pwmFF = app.PWM_DEADZONE + ...
                    (app.SETPOINT_RPM / max(app.RPM_AT_MAX_PWM, eps)) * ...
                    (app.PWM_AT_MAX_RPM - app.PWM_DEADZONE);

                pwmFF = max(app.PWM_MIN, min(app.PWM_MAX, pwmFF));

                dMeas = (app.rpmFilt - app.prevRPMFilt) / dt;
                app.dFiltered = app.dAlpha * dMeas + (1 - app.dAlpha) * app.dFiltered;

                integralCandidate = app.integral + errNow * dt;
                integralCandidate = max(-app.I_LIMIT, min(app.I_LIMIT, integralCandidate));

                correctionRaw = app.Kp * errNow + ...
                    app.Ki * integralCandidate - ...
                    app.Kd * app.dFiltered;

                correctionRaw = max(-app.PWM_CORRECTION_LIMIT, ...
                    min(app.PWM_CORRECTION_LIMIT, correctionRaw));

                uRaw = pwmFF + correctionRaw;

                if (uRaw > app.PWM_MIN && uRaw < app.PWM_MAX) || ...
                        (uRaw >= app.PWM_MAX && errNow < 0) || ...
                        (uRaw <= app.PWM_MIN && errNow > 0)
                    app.integral = integralCandidate;
                end

                correction = app.Kp * errNow + ...
                    app.Ki * app.integral - ...
                    app.Kd * app.dFiltered;

                correction = max(-app.PWM_CORRECTION_LIMIT, ...
                    min(app.PWM_CORRECTION_LIMIT, correction));

                pwmCmd = pwmFF + correction;
                pwmCmd = round(max(app.PWM_MIN, min(app.PWM_MAX, pwmCmd)));

                app.prevError = errNow;
                app.prevRPMFilt = app.rpmFilt;
            end

            pwmCmd = app.limitPwmSlew(pwmCmd, dt);
            app.applyMotorPWM(pwmCmd);

            if app.forwardDir
                dirSign = 1;
            else
                dirSign = -1;
            end

            rpmPlot    = dirSign * app.rpmFilt;
            targetPlot = dirSign * app.SETPOINT_RPM;
            errPlot    = targetPlot - rpmPlot;
            pwmPlot    = dirSign * app.lastPWM;

            app.ActualRPMLabel.Text = sprintf("Actual: %.1f RPM", rpmPlot);
            app.ErrorLabel.Text     = sprintf("Error: %.1f RPM", errPlot);
            app.PWMLabel.Text       = sprintf("PWM: %d / 255", round(pwmPlot));

            app.tBuf(1:end-1)      = app.tBuf(2:end);
            app.targetBuf(1:end-1) = app.targetBuf(2:end);
            app.rpmBuf(1:end-1)    = app.rpmBuf(2:end);
            app.errBuf(1:end-1)    = app.errBuf(2:end);
            app.pwmBuf(1:end-1)    = app.pwmBuf(2:end);

            app.tBuf(end)      = t;
            app.targetBuf(end) = targetPlot;
            app.rpmBuf(end)    = rpmPlot;
            app.errBuf(end)    = errPlot;
            app.pwmBuf(end)    = pwmPlot;

            app.controlPlotCounter = app.controlPlotCounter + 1;

            if mod(app.controlPlotCounter, 3) == 0
                set(app.lineTarget, "XData", app.tBuf, "YData", app.targetBuf);
                set(app.lineRPM,    "XData", app.tBuf, "YData", app.rpmBuf);
                set(app.lineErr,    "XData", app.tBuf, "YData", app.errBuf);
                set(app.linePWM,    "XData", app.tBuf, "YData", app.pwmBuf);
                drawnow limitrate;
            end
        end

        function applyMotorPWM(app, pwm)

            if ~app.hwConnected || isempty(app.a)
                return;
            end

            pwm = max(app.PWM_MIN, min(app.PWM_MAX, pwm));
            app.lastPWM = pwm;

            duty = pwm / 255;

            if pwm == 0
                writePWMDutyCycle(app.a, app.R_PWM, 0);
                writePWMDutyCycle(app.a, app.L_PWM, 0);
                return;
            end

            if app.forwardDir
                writePWMDutyCycle(app.a, app.R_PWM, duty);
                writePWMDutyCycle(app.a, app.L_PWM, 0);
            else
                writePWMDutyCycle(app.a, app.R_PWM, 0);
                writePWMDutyCycle(app.a, app.L_PWM, duty);
            end
        end

        function initConnectionTab(app)
            % Boards
            app.BoardsDropDown.Items = {'Uno','Nano3','Mega2560','Leonardo','Due'};
            if isempty(app.BoardsDropDown.Value)
                app.BoardsDropDown.Value = 'Nano3';
            end

            % Ports
            app.refreshPorts();

            % Buttons initial state
            app.ConnectButton.Enable        = "on";
            app.DisconnectButton.Enable     = "off";
            app.TestBlinkD13Button.Enable   = "off";

            % Motor controls disabled until connected
            app.RunButton.Enable  = "off";
            app.StopButton.Enable = "off";

            app.setStatus("err","Not connected");
        end

        function refreshPorts(app)
            % Store current selection before modifying Items
            current = string(app.AvailablePortsDropDown.Value);

            % Show scanning state (yellow)
            app.setStatus("warn","Scanning ports...");
            drawnow;

            % Small scan window to reduce temporary OS glitches
            scanSec = 0.6;  % adjust if you want (0.3–1.0 is fine)
            tStart  = tic;

            portsAll = strings(1,0);
            while toc(tStart) < scanSec
                portsNow = serialportlist("available");
                portsAll = unique([portsAll, portsNow(:)']); %ok
                pause(0.1);
                drawnow;
            end

            ports = portsAll;

            % Prefer Arduino-like ports
            isCandidate = contains(ports,"/dev/ttyACM") | ...
                contains(ports,"/dev/ttyUSB") | ...
                startsWith(ports,"COM");
            if any(isCandidate)
                ports = ports(isCandidate);
            end

            % Force row format (1xN)
            ports = ports(:)';

            % Case: No ports detected
            if isempty(ports)

                % If already connected, keep current port visible
                if app.hwConnected && strlength(current) > 0
                    app.AvailablePortsDropDown.Items = current;
                    app.AvailablePortsDropDown.Value = current;

                    % Return to connected state (green)
                    app.setStatus("ok","Still connected (no ports detected during scan)");
                else
                    app.AvailablePortsDropDown.Items = "";
                    app.AvailablePortsDropDown.Value = "";
                    app.setStatus("err","No serial ports found");
                end

                return;
            end

            % If connected and current port not in list, preserve it
            if app.hwConnected && strlength(current) > 0 && ~any(ports == current)
                ports = [current ports];
            end

            % Update dropdown items
            app.AvailablePortsDropDown.Items = ports;

            % Restore previous selection if possible
            if strlength(current) > 0 && any(ports == current)
                app.AvailablePortsDropDown.Value = current;
            else
                app.AvailablePortsDropDown.Value = ports(1);
            end

            % Final state after scan:
            % - Green if connected
            % - Red if not connected
            if app.hwConnected
                app.setStatus("ok","Scan complete (connected)");
            else
                app.setStatus("err","Scan complete (not connected)");
            end
        end

        function setStatus(app, state, msg)
            % state: "ok" | "warn" | "err" | "test"
            % Updates status text and lamp color

            app.StatusNotconnectedLabel.Text = "Status: " + string(msg);

            switch lower(string(state))
                case "ok"
                    app.StatusLamp.Color = [0.2 0.8 0.2];     % Green (connected / success)

                case "warn"
                    app.StatusLamp.Color = [0.95 0.75 0.2];   % Yellow (scanning / connecting)

                case "test"
                    app.StatusLamp.Color = [0.2 0.5 0.95];    % Blue (testing)

                otherwise
                    app.StatusLamp.Color = [0.9 0.3 0.3];     % Red (disconnected / error)
            end
        end

        function connectArduino(app)

            app.stopNow();

            port  = string(app.AvailablePortsDropDown.Value);
            board = string(app.BoardsDropDown.Value);

            if strlength(port) == 0
                app.setStatus("err","Select a port first");
                return;
            end

            app.ConnectButton.Enable      = "off";
            app.DisconnectButton.Enable   = "off";
            app.RefreshButton.Enable      = "off";
            app.TestBlinkD13Button.Enable = "off";
            app.StartButton.Enable        = "off";
            app.StopButton_2.Enable       = "off";

            app.setStatus("warn","Connecting to " + port + " (" + board + ") ...");
            drawnow;

            try
                app.COM   = char(port);
                app.BOARD = char(board);

                try
                    tmp = serialport(app.COM, 115200, "Timeout", 0.5);
                    pause(0.05);
                    delete(tmp);
                catch
                    app.setStatus("err","Port cannot be opened (busy/unavailable)");
                    uialert(app.UIFigure, ...
                        "Cannot open the selected COM port." + newline + ...
                        "Possible causes:" + newline + ...
                        "- Port is already in use" + newline + ...
                        "- Driver issue" + newline + ...
                        "- Wrong COM selected", ...
                        "Connection Error");
                    app.afterConnectErrorRefresh();
                    return;
                end

                app.a = arduino(app.COM, app.BOARD, "Libraries","rotaryEncoder");

                configurePin(app.a, app.R_PWM, "PWM");
                configurePin(app.a, app.L_PWM, "PWM");
                configurePin(app.a, app.R_EN , "DigitalOutput");
                configurePin(app.a, app.L_EN , "DigitalOutput");

                configurePin(app.a, app.R_IS, "AnalogInput");
                configurePin(app.a, app.L_IS, "AnalogInput");

                % Enable BTS7960 once after connection
                writeDigitalPin(app.a, app.R_EN, 1);
                writeDigitalPin(app.a, app.L_EN, 1);

                writePWMDutyCycle(app.a, app.R_PWM, 0);
                writePWMDutyCycle(app.a, app.L_PWM, 0);

                app.enc = rotaryEncoder(app.a, app.ENC_A, app.ENC_B, round(app.CPR_TOTAL));
                app.lastCount = readCount(app.enc);

                app.hwConnected = true;
                app.lastPWM = 0;

                app.setStatus("ok","Connected: " + port + " (" + board + ")");

                app.ConnectButton.Enable      = "off";
                app.DisconnectButton.Enable   = "on";
                app.RefreshButton.Enable      = "on";
                app.TestBlinkD13Button.Enable = "on";

                app.RunButton.Enable          = "on";
                app.StopButton.Enable         = "off";

                app.StartButton.Enable        = "on";
                app.StopButton_2.Enable       = "off";

            catch ME
                app.hwConnected = false;
                app.a   = [];
                app.enc = [];

                app.setStatus("err","Connection failed");

                app.ConnectButton.Enable      = "on";
                app.DisconnectButton.Enable   = "off";
                app.RefreshButton.Enable      = "on";
                app.TestBlinkD13Button.Enable = "off";

                app.RunButton.Enable          = "off";
                app.StopButton.Enable         = "off";

                app.StartButton.Enable        = "off";
                app.StopButton_2.Enable       = "off";

                uialert(app.UIFigure, ...
                    "Unable to connect to the selected board." + newline + ...
                    "MATLAB says: " + string(ME.message) + newline + newline + ...
                    "Try:" + newline + ...
                    "- Select the correct board" + newline + ...
                    "- Select the correct COM port" + newline + ...
                    "- Unplug/plug the Arduino", ...
                    "Connection Error");

                app.afterConnectErrorRefresh();
            end
        end

        function disconnectArduino(app)
            app.stopNow();  % Ensure motor is stopped

            try
                if ~isempty(app.a)
                    writePWMDutyCycle(app.a, app.R_PWM, 0);
                    writePWMDutyCycle(app.a, app.L_PWM, 0);

                    writeDigitalPin(app.a, app.R_EN, 0);
                    writeDigitalPin(app.a, app.L_EN, 0);
                end
            catch
            end

            app.hwConnected = false;
            app.enc = [];
            app.a   = [];

            app.Optimizing = false;
            app.optStopFlag = true;

            app.ConnectButton.Enable      = "on";
            app.DisconnectButton.Enable   = "off";
            app.TestBlinkD13Button.Enable = "off";

            app.RunButton.Enable      = "off";
            app.StopButton.Enable     = "off";
            app.SaveDataButton.Enable = "off";

            app.StartButton.Enable    = "off";
            app.StopButton_2.Enable   = "off";

            app.refreshPorts();
        end

        function testConnection(app)
            if ~app.hwConnected || isempty(app.a)
                app.setStatus("err","Not connected");
                return;
            end

            % Blue during test
            app.setStatus("test","Testing connection (blink D13) ...");
            drawnow;

            try
                configurePin(app.a,"D13","DigitalOutput");
                writeDigitalPin(app.a,"D13",1); pause(1);
                writeDigitalPin(app.a,"D13",0);

                % Back to green after successful test
                app.setStatus("ok","Test OK (blinked D13)");
            catch ME
                % Red on failure
                app.setStatus("err","Test failed");
                uialert(app.UIFigure, ME.message, "Test Failed");
            end
        end


        function afterConnectErrorRefresh(app)
            app.stopNow();

            app.hwConnected = false;
            app.enc = [];
            app.a   = [];

            app.Optimizing = false;
            app.optStopFlag = true;

            app.ConnectButton.Enable      = "on";
            app.DisconnectButton.Enable   = "off";
            app.TestBlinkD13Button.Enable = "off";
            app.RefreshButton.Enable      = "on";

            app.RunButton.Enable      = "off";
            app.StopButton.Enable     = "off";
            app.SaveDataButton.Enable = "off";

            app.StartButton.Enable    = "off";
            app.StopButton_2.Enable   = "off";

            app.refreshPorts();
        end

        function clearPlots(app)
            if isempty(app.UIAxes) || ~isvalid(app.UIAxes)
                return;
            end

            app.tBuf(:)      = nan;
            app.targetBuf(:) = nan;
            app.rpmBuf(:)    = nan;
            app.errBuf(:)    = nan;
            app.pwmBuf(:)    = nan;

            app.saveEndIndex = NaN;

            if ~isempty(app.lineTarget) && isvalid(app.lineTarget)
                set(app.lineTarget, "XData", app.tBuf, "YData", app.targetBuf);
            end
            if ~isempty(app.lineRPM) && isvalid(app.lineRPM)
                set(app.lineRPM, "XData", app.tBuf, "YData", app.rpmBuf);
            end
            if ~isempty(app.lineErr) && isvalid(app.lineErr)
                set(app.lineErr, "XData", app.tBuf, "YData", app.errBuf);
            end
            if ~isempty(app.linePWM) && isvalid(app.linePWM)
                set(app.linePWM, "XData", app.tBuf, "YData", app.pwmBuf);
            end

            drawnow;
        end

        function pwmOut = limitPwmSlew(app, pwmIn, dt)
            % Limit PWM change rate for smoother acceleration and deceleration

            pwmIn = max(app.PWM_MIN, min(app.PWM_MAX, pwmIn));
            last  = max(app.PWM_MIN, min(app.PWM_MAX, app.lastPWM));

            stepUp   = app.pwmSlewUp * dt;
            stepDown = app.pwmSlewDown * dt;

            if pwmIn > last
                pwmOut = min(pwmIn, last + stepUp);
            else
                pwmOut = max(pwmIn, last - stepDown);
            end

            pwmOut = round(pwmOut);
        end

        function updatePidSpinnerState(app)
            if app.Running
                state = "off";
            else
                state = "on";
            end

            if ~isempty(app.KpSpinner) && isvalid(app.KpSpinner)
                app.KpSpinner.Enable = state;
            end

            if ~isempty(app.KiSpinner) && isvalid(app.KiSpinner)
                app.KiSpinner.Enable = state;
            end

            if ~isempty(app.KdSpinner) && isvalid(app.KdSpinner)
                app.KdSpinner.Enable = state;
            end
        end

        function saveResults(app)
            % Save current plotted data to Results folder:
            % 1) PNG image of the current graph
            % 2) Excel file with table data
            % 3) Excel chart generated from the saved data

            try
                % -----------------------------
                % Validate data
                % -----------------------------
                validIdx = ~isnan(app.tBuf) & ~isnan(app.targetBuf) & ...
                    ~isnan(app.rpmBuf) & ~isnan(app.errBuf) & ~isnan(app.pwmBuf);

                % If stop button was pressed before saving, keep only samples up to the
                % last valid point that existed before stop forced PWM to zero
                if ~isnan(app.saveEndIndex)
                    trimmedIdx = false(size(validIdx));
                    trimmedIdx(1:app.saveEndIndex) = validIdx(1:app.saveEndIndex);
                    validIdx = trimmedIdx;
                end

                if ~any(validIdx)
                    uialert(app.UIFigure, 'No data available to save.', 'Save Data');
                    return;
                end

                tData      = app.tBuf(validIdx).';
                targetData = app.targetBuf(validIdx).';
                rpmData    = app.rpmBuf(validIdx).';
                errData    = app.errBuf(validIdx).';
                pwmData    = app.pwmBuf(validIdx).';

                % -----------------------------
                % Create Results folder
                % -----------------------------
                appFolder = fileparts(mfilename('fullpath'));
                resultsFolder = fullfile(appFolder, 'Results');

                if ~exist(resultsFolder, 'dir')
                    mkdir(resultsFolder);
                end

                % -----------------------------
                % Build file names
                % -----------------------------
                timeStamp = char(datetime("now","Format","yyyy-MM-dd_HH-mm-ss"));
                baseName  = ['MotorResponse_' timeStamp];

                pngFile   = fullfile(resultsFolder, [baseName '.png']);
                excelFile = fullfile(resultsFolder, [baseName '.xlsx']);

                % -----------------------------
                % Save PNG from current data
                % -----------------------------
                tempFig = figure('Visible', 'off');
                tempAx = axes(tempFig);
                hold(tempAx, 'on');
                grid(tempAx, 'on');

                plot(tempAx, tData, targetData, 'LineWidth', 1.5);
                plot(tempAx, tData, rpmData,    'LineWidth', 1.5);
                plot(tempAx, tData, errData,    'LineWidth', 1.5);
                plot(tempAx, tData, pwmData,    'LineWidth', 1.5);

                title(tempAx, 'Motor Response');
                xlabel(tempAx, 'Time (s)');
                ylabel(tempAx, 'RPM / PWM');
                legend(tempAx, {'Reference','Actual RPM','Error','PWM'}, 'Location', 'best');
                hold(tempAx, 'off');

                exportgraphics(tempAx, pngFile, 'Resolution', 300);
                close(tempFig);

                % -----------------------------
                % Prepare table for Excel
                % -----------------------------
                T = table(tData, targetData, rpmData, errData, pwmData, ...
                    'VariableNames', {'Time_s', 'Reference_RPM', 'Actual_RPM', 'Error_RPM', 'PWM'});

                writetable(T, excelFile, 'Sheet', 'Data', 'Range', 'A1');

                % -----------------------------
                % Create Excel chart using ActiveX
                % -----------------------------
                try
                    excel = actxserver('Excel.Application');
                    excel.Visible = false;
                    wb = excel.Workbooks.Open(excelFile);
                    ws = wb.Worksheets.Item('Data');

                    % Add chart object
                    chartObj = ws.ChartObjects.Add(420, 20, 500, 300);
                    chart = chartObj.Chart;

                    % Chart type: line
                    chart.ChartType = 4; % xlLine

                    % Data range
                    lastRow = size(T, 1) + 1;
                    xRange = ws.Range(['A2:A' num2str(lastRow)]);

                    % Series 1: Reference
                    s1 = chart.SeriesCollection.NewSeries;
                    s1.Name = 'Reference';
                    s1.XValues = xRange;
                    s1.Values = ws.Range(['B2:B' num2str(lastRow)]);

                    % Series 2: Actual RPM
                    s2 = chart.SeriesCollection.NewSeries;
                    s2.Name = 'Actual RPM';
                    s2.XValues = xRange;
                    s2.Values = ws.Range(['C2:C' num2str(lastRow)]);

                    % Series 3: Error
                    s3 = chart.SeriesCollection.NewSeries;
                    s3.Name = 'Error';
                    s3.XValues = xRange;
                    s3.Values = ws.Range(['D2:D' num2str(lastRow)]);

                    % Series 4: PWM
                    s4 = chart.SeriesCollection.NewSeries;
                    s4.Name = 'PWM';
                    s4.XValues = xRange;
                    s4.Values = ws.Range(['E2:E' num2str(lastRow)]);

                    % Titles
                    chart.HasTitle = true;
                    chart.ChartTitle.Text = 'Motor Response';

                    chart.Axes(1).HasTitle = true;
                    chart.Axes(1).AxisTitle.Text = 'Time (s)';

                    chart.Axes(2).HasTitle = true;
                    chart.Axes(2).AxisTitle.Text = 'RPM / PWM';

                    wb.Save;
                    wb.Close(false);
                    excel.Quit;

                    delete(chart);
                    delete(chartObj);
                    delete(ws);
                    delete(wb);
                    delete(excel);

                catch
                    % If Excel ActiveX fails, keep the data table saved at least
                end

                uialert(app.UIFigure, ...
                    ['Data saved successfully in:' newline resultsFolder], ...
                    'Save Data', ...
                    'Icon', 'success');

            catch ME
                uialert(app.UIFigure, ...
                    ['Failed to save data.' newline newline ME.message], ...
                    'Save Error');
            end
        end

        function startMotorOptimization(app)

            if ~app.hwConnected || isempty(app.a) || isempty(app.enc)
                uialert(app.UIFigure,"Connect Arduino first","Error");
                return;
            end

            if app.Running
                uialert(app.UIFigure,"Stop motor first","Error");
                return;
            end

            if app.Optimizing
                return;
            end

            nPop  = round(app.NumberofStarsSpinner.Value);
            MaxIt = round(app.IterationsSpinner_2.Value);

            if nPop < 1 || MaxIt < 1
                uialert(app.UIFigure, ...
                    "Number of Stars and Iterations must be greater than zero.", ...
                    "Invalid Optimizer Settings");
                return;
            end

            VarMin = [app.MinKpSpinner.Value app.MinKiSpinner.Value app.MinKdSpinner.Value];
            VarMax = [app.MaxKpSpinner.Value app.MaxKiSpinner.Value app.MaxKdSpinner.Value];

            if any(~isfinite(VarMin)) || any(~isfinite(VarMax)) || any(VarMax <= VarMin)
                uialert(app.UIFigure, ...
                    "Each Max PID limit must be greater than its Min limit.", ...
                    "Invalid PID Limits");
                return;
            end

            % Stop timer before optimization
            if ~isempty(app.tmr) && isvalid(app.tmr)
                try
                    stop(app.tmr);
                catch
                end
            end

            % Re-enable BTS7960 before optimization
            try
                writeDigitalPin(app.a, app.R_EN, 1);
                writeDigitalPin(app.a, app.L_EN, 1);
                writePWMDutyCycle(app.a, app.R_PWM, 0);
                writePWMDutyCycle(app.a, app.L_PWM, 0);
            catch
            end

            app.Optimizing = true;
            app.optStopFlag = false;

            app.StartButton.Enable = "off";
            app.StopButton_2.Enable = "on";
            app.RunButton.Enable = "off";
            app.StopButton.Enable = "off";
            app.SaveDataButton.Enable = "off";

            app.KpSpinner.Enable = "off";
            app.KiSpinner.Enable = "off";
            app.KdSpinner.Enable = "off";

            app.NumberofStarsSpinner.Enable = "off";
            app.IterationsSpinner_2.Enable = "off";
            app.MinKpSpinner.Enable = "off";
            app.MinKiSpinner.Enable = "off";
            app.MinKdSpinner.Enable = "off";
            app.MaxKpSpinner.Enable = "off";
            app.MaxKiSpinner.Enable = "off";
            app.MaxKdSpinner.Enable = "off";

            app.UITable.Data = table([], [], [], [], [], ...
                'VariableNames', {'Iteration','Kp','Ki','Kd','Cost'});

            cla(app.UIAxes_2);
            grid(app.UIAxes_2, "on");
            app.UIAxes_2.XLabel.String = "Time (s)";
            app.UIAxes_2.YLabel.String = "RPM / PWM";
            title(app.UIAxes_2, "Motor Response");

            app.optLineTarget = [];
            app.optLineRPM = [];
            app.optLinePWM = [];

            drawnow;

            nVar = 3;
            star = struct('pos',[],'cost',[]);

            globalBest.cost = inf;
            globalBest.pos  = [];

            try
                % ===== Initial stars =====
                for i = 1:nPop
                    if app.optStopFlag
                        break;
                    end

                    star(i).pos  = VarMin + rand(1,nVar).*(VarMax-VarMin);
                    star(i).cost = app.evaluatePidCandidate(star(i).pos);

                    if star(i).cost < globalBest.cost
                        globalBest = star(i);
                    end

                    drawnow;
                end

                % ===== Main BHA loop =====
                for it = 1:MaxIt
                    if app.optStopFlag
                        break;
                    end

                    [~,idxBH] = min([star.cost]);
                    BH = star(idxBH);

                    if globalBest.cost < BH.cost
                        BH = globalBest;
                    end

                    movedStar = star;

                    % Move stars toward black hole
                    for i = 1:nPop
                        if app.optStopFlag
                            break;
                        end

                        if i == idxBH
                            continue;
                        end

                        movedStar(i).pos = star(i).pos + rand(1,nVar).*(BH.pos - star(i).pos);

                        movedStar(i).pos = max(movedStar(i).pos, VarMin);
                        movedStar(i).pos = min(movedStar(i).pos, VarMax);

                        movedStar(i).cost = app.evaluatePidCandidate(movedStar(i).pos);

                        if movedStar(i).cost < globalBest.cost
                            globalBest = movedStar(i);
                        end

                        drawnow;
                    end

                    if app.optStopFlag
                        break;
                    end

                    % Event horizon
                    [~,idxBHNew] = min([movedStar.cost]);

                    allCosts = [movedStar.cost];
                    sumCosts = sum(allCosts);

                    if sumCosts <= 0 || ~isfinite(sumCosts)
                        eventRadius = 0;
                    else
                        eventRadius = abs(movedStar(idxBHNew).cost / sumCosts);
                    end

                    for i = 1:nPop
                        if app.optStopFlag
                            break;
                        end

                        if i ~= idxBHNew
                            if norm(movedStar(idxBHNew).pos - movedStar(i).pos) < eventRadius

                                if rand < 0.5
                                    movedStar(i).pos = VarMin + rand(1,nVar).*(VarMax-VarMin);
                                else
                                    movedStar(i).pos = globalBest.pos + ...
                                        0.1*randn(1,nVar).*(VarMax-VarMin);
                                end

                                movedStar(i).pos = max(movedStar(i).pos, VarMin);
                                movedStar(i).pos = min(movedStar(i).pos, VarMax);

                                movedStar(i).cost = app.evaluatePidCandidate(movedStar(i).pos);

                                if movedStar(i).cost < globalBest.cost
                                    globalBest = movedStar(i);
                                end
                            end
                        end

                        drawnow;
                    end

                    star = movedStar;

                    if ~isempty(globalBest.pos)
                        newRow = table(it, globalBest.pos(1), globalBest.pos(2), ...
                            globalBest.pos(3), globalBest.cost, ...
                            'VariableNames', {'Iteration','Kp','Ki','Kd','Cost'});

                        app.UITable.Data = [app.UITable.Data; newRow];
                    end

                    drawnow;
                end

                if ~isempty(globalBest.pos)
                    app.Kp = globalBest.pos(1);
                    app.Ki = globalBest.pos(2);
                    app.Kd = globalBest.pos(3);

                    app.KpSpinner.Value = app.Kp;
                    app.KiSpinner.Value = app.Ki;
                    app.KdSpinner.Value = app.Kd;
                end

            catch ME
                app.applyMotorPWM(0);
                uialert(app.UIFigure, ME.message, "Optimization Error");
            end

            app.applyMotorPWM(0);
            app.finishMotorOptimizationUi();
        end

        function updateOptimizationResponsePlot(app, tData, rpmData, pwmData, targetRPM)
            if isempty(app.UIAxes_2) || ~isvalid(app.UIAxes_2)
                return;
            end

            if app.forwardDir
                dirSign = 1;
            else
                dirSign = -1;
            end

            targetData = dirSign * targetRPM * ones(size(tData));
            rpmDataPlot = dirSign * rpmData;
            pwmDataPlot = dirSign * pwmData;

            if isempty(app.optLineTarget) || ~isvalid(app.optLineTarget) || ...
                    isempty(app.optLineRPM) || ~isvalid(app.optLineRPM) || ...
                    isempty(app.optLinePWM) || ~isvalid(app.optLinePWM)

                cla(app.UIAxes_2);
                hold(app.UIAxes_2, "on");
                grid(app.UIAxes_2, "on");

                app.optLineTarget = plot(app.UIAxes_2, tData, targetData, "LineWidth", 1.2);
                app.optLineRPM    = plot(app.UIAxes_2, tData, rpmDataPlot, "LineWidth", 1.2);
                app.optLinePWM    = plot(app.UIAxes_2, tData, pwmDataPlot, "LineWidth", 1.2);

                title(app.UIAxes_2, "Motor Response");
                xlabel(app.UIAxes_2, "Time (s)");
                ylabel(app.UIAxes_2, "RPM / PWM");
                legend(app.UIAxes_2, {"Target","Actual RPM","PWM"}, "Location", "northeast");

                hold(app.UIAxes_2, "off");

            else
                set(app.optLineTarget, "XData", tData, "YData", targetData);
                set(app.optLineRPM,    "XData", tData, "YData", rpmDataPlot);
                set(app.optLinePWM,    "XData", tData, "YData", pwmDataPlot);
            end
        end

        function pwmOut = limitPwmSlewLocal(app, pwmIn, lastPWMValue, dt)
            pwmIn = max(app.PWM_MIN, min(app.PWM_MAX, pwmIn));
            lastPWMValue = max(app.PWM_MIN, min(app.PWM_MAX, lastPWMValue));

            stepUp = app.pwmSlewUp * dt;
            stepDown = app.pwmSlewDown * dt;

            if pwmIn > lastPWMValue
                pwmOut = min(pwmIn, lastPWMValue + stepUp);
            else
                pwmOut = max(pwmIn, lastPWMValue - stepDown);
            end

            pwmOut = round(pwmOut);
        end

        function finishMotorOptimizationUi(app)
            app.applyMotorPWM(0);

            app.Optimizing = false;
            app.optStopFlag = false;

            app.stopNow();
            pause(0.1);

            app.integral  = 0;
            app.prevError = 0;
            app.dFiltered = 0;
            app.rpmFilt   = 0;
            app.lastPWM   = 0;
            app.prevRPMFilt = 0;
            app.firstSample = true;

            if app.hwConnected && ~isempty(app.enc)
                try
                    app.lastCount = readCount(app.enc);
                catch
                    app.lastCount = 0;
                end
            end

            if app.hwConnected
                app.StartButton.Enable = "on";
                app.RunButton.Enable   = "on";
            else
                app.StartButton.Enable = "off";
                app.RunButton.Enable   = "off";
            end

            app.StopButton_2.Enable = "off";
            app.StopButton.Enable   = "off";

            app.KpSpinner.Enable = "on";
            app.KiSpinner.Enable = "on";
            app.KdSpinner.Enable = "on";

            app.NumberofStarsSpinner.Enable = "on";
            app.IterationsSpinner_2.Enable  = "on";
            app.MinKpSpinner.Enable = "on";
            app.MinKiSpinner.Enable = "on";
            app.MinKdSpinner.Enable = "on";
            app.MaxKpSpinner.Enable = "on";
            app.MaxKiSpinner.Enable = "on";
            app.MaxKdSpinner.Enable = "on";

            app.ActualRPMLabel.Text = "Actual: -- RPM";
            app.ErrorLabel.Text     = "Error: -- RPM";
            app.PWMLabel.Text       = "PWM: -- / 255";

            drawnow;

            if app.hwConnected
                uialert(app.UIFigure, ...
                    "Optimization completed successfully.", ...
                    "Done", ...
                    "Icon", "success");
            end
        end

        function stopNow(app)
            % Stop software loop first
            app.stopFlag = true;
            app.Running = false;

            % Stop timer safely
            if ~isempty(app.tmr) && isvalid(app.tmr)
                try
                    if strcmp(app.tmr.Running, "on")
                        stop(app.tmr);
                    end
                catch
                end
            end

            % HARD motor stop
            try
                if app.hwConnected && ~isempty(app.a)
                    writePWMDutyCycle(app.a, app.R_PWM, 0);
                    writePWMDutyCycle(app.a, app.L_PWM, 0);

                    pause(0.03);

                    writeDigitalPin(app.a, app.R_EN, 0);
                    writeDigitalPin(app.a, app.L_EN, 0);
                end
            catch
            end

            app.lastPWM = 0;

            % Reset runtime states
            app.integral = 0;
            app.prevError = 0;
            app.dFiltered = 0;
            app.rpmFilt = 0;
            app.prevRPMFilt = 0;
            app.firstSample = true;

            % Save last valid index
            if ~isempty(app.tBuf)
                validIdx = find(~isnan(app.tBuf), 1, 'last');
                if ~isempty(validIdx)
                    app.saveEndIndex = validIdx;
                end
            end

            % Reset encoder reference
            if app.hwConnected && ~isempty(app.enc)
                try
                    app.lastCount = readCount(app.enc);
                catch
                    app.lastCount = 0;
                end
            end

            % Restore UI
            if app.hwConnected && ~app.Optimizing
                app.RunButton.Enable = "on";
                app.StartButton.Enable = "on";
            else
                app.RunButton.Enable = "off";
                app.StartButton.Enable = "off";
            end

            app.StopButton.Enable = "off";
            app.StopButton_2.Enable = "off";

            app.ActualRPMLabel.Text = "Actual: -- RPM";
            app.ErrorLabel.Text = "Error: -- RPM";
            app.PWMLabel.Text = "PWM: 0 / 255";

            app.updatePidSpinnerState();

            drawnow limitrate;
        end

        function J = evaluatePidCandidate(app, K)

            testKp = K(1);
            testKi = K(2);
            testKd = K(3);

            targetRPM = app.RPMTargetSpinner.Value;

            if targetRPM <= 0
                targetRPM = app.SETPOINT_RPM;
            end

            if targetRPM <= 0
                J = 1e8;
                return;
            end

            tLocal = [];
            rpmLocal = [];
            pwmLocal = [];

            app.applyMotorPWM(0);
            pause(0.15);

            integralLocal = 0;
            rpmFiltLocal = 0;
            prevRPMFiltLocal = 0;
            dFilteredLocal = 0;
            lastPWMLocal = 0;
            firstSampleLocal = true;

            try
                lastCountLocal = readCount(app.enc);
            catch
                app.applyMotorPWM(0);
                J = 1e8;
                return;
            end

            tStart = tic;
            tPrevLocal = 0;

            while toc(tStart) < app.optTestSec

                if app.optStopFlag || ~app.hwConnected
                    app.applyMotorPWM(0);
                    J = 1e8;
                    return;
                end

                tNow = toc(tStart);
                dtReal = tNow - tPrevLocal;

                if dtReal < app.TsTarget
                    pause(app.TsTarget / 5);
                    drawnow limitrate;
                    continue;
                end

                if dtReal <= 0 || ~isfinite(dtReal)
                    continue;
                end

                tPrevLocal = tNow;

                try
                    countNow = readCount(app.enc);
                catch
                    app.applyMotorPWM(0);
                    J = 1e8;
                    return;
                end

                delta = countNow - lastCountLocal;
                lastCountLocal = countNow;

                rpmRaw = abs((double(delta) * 60.0) / (double(app.CPR_TOTAL) * dtReal));

                if ~isfinite(rpmRaw)
                    app.applyMotorPWM(0);
                    J = 1e8;
                    return;
                end

                dt = app.TsTarget;

                if firstSampleLocal
                    rpmFiltLocal = rpmRaw;
                    prevRPMFiltLocal = rpmRaw;
                    integralLocal = 0;
                    dFilteredLocal = 0;
                    firstSampleLocal = false;
                else
                    rpmFiltLocal = app.rpmAlpha * rpmRaw + ...
                        (1 - app.rpmAlpha) * rpmFiltLocal;
                end

                errNow = targetRPM - rpmFiltLocal;

                pwmFF = app.PWM_DEADZONE + ...
                    (targetRPM / max(app.RPM_AT_MAX_PWM, eps)) * ...
                    (app.PWM_AT_MAX_RPM - app.PWM_DEADZONE);

                pwmFF = max(app.PWM_MIN, min(app.PWM_MAX, pwmFF));

                dMeas = (rpmFiltLocal - prevRPMFiltLocal) / dt;
                dFilteredLocal = app.dAlpha * dMeas + ...
                    (1 - app.dAlpha) * dFilteredLocal;

                integralCandidate = integralLocal + errNow * dt;
                integralCandidate = max(-app.I_LIMIT, ...
                    min(app.I_LIMIT, integralCandidate));

                correctionRaw = testKp * errNow + ...
                    testKi * integralCandidate - ...
                    testKd * dFilteredLocal;

                correctionRaw = max(-app.PWM_CORRECTION_LIMIT, ...
                    min(app.PWM_CORRECTION_LIMIT, correctionRaw));

                uRaw = pwmFF + correctionRaw;

                if (uRaw > app.PWM_MIN && uRaw < app.PWM_MAX) || ...
                        (uRaw >= app.PWM_MAX && errNow < 0) || ...
                        (uRaw <= app.PWM_MIN && errNow > 0)
                    integralLocal = integralCandidate;
                end

                correction = testKp * errNow + ...
                    testKi * integralLocal - ...
                    testKd * dFilteredLocal;

                correction = max(-app.PWM_CORRECTION_LIMIT, ...
                    min(app.PWM_CORRECTION_LIMIT, correction));

                pwmCmd = pwmFF + correction;
                pwmCmd = round(max(app.PWM_MIN, min(app.PWM_MAX, pwmCmd)));

                pwmCmd = app.limitPwmSlewLocal(pwmCmd, lastPWMLocal, dt);
                lastPWMLocal = pwmCmd;

                app.applyMotorPWM(pwmCmd);

                tLocal(end+1,1) = tNow;
                rpmLocal(end+1,1) = rpmFiltLocal;
                pwmLocal(end+1,1) = pwmCmd;

                app.ActualRPMLabel.Text = sprintf("Actual: %.1f RPM", rpmFiltLocal);
                app.ErrorLabel.Text     = sprintf("Error: %.1f RPM", targetRPM - rpmFiltLocal);
                app.PWMLabel.Text       = sprintf("PWM: %d / 255", round(pwmCmd));

                if mod(numel(tLocal), 5) == 0
                    app.updateOptimizationResponsePlot(tLocal, rpmLocal, pwmLocal, targetRPM);
                    drawnow limitrate;
                end

                prevRPMFiltLocal = rpmFiltLocal;
            end

            app.applyMotorPWM(0);

            if ~isempty(tLocal)
                app.updateOptimizationResponsePlot(tLocal, rpmLocal, pwmLocal, targetRPM);
                drawnow limitrate;
            end

            if numel(tLocal) < 8 || any(~isfinite(rpmLocal)) || any(~isfinite(pwmLocal))
                J = 1e8;
                return;
            end

            err = targetRPM - rpmLocal;
            absError = abs(err);

            ITAE = trapz(tLocal, tLocal .* absError);
            ITAE_norm = ITAE / max(targetRPM * app.optTestSec^2, eps);

            band = 0.10 * targetRPM;
            inBand = abs(rpmLocal - targetRPM) <= band;

            settleIdx = numel(rpmLocal);

            for k = 1:(numel(rpmLocal)-5)
                if all(inBand(k:k+4))
                    settleIdx = k;
                    break;
                end
            end

            lastPart = settleIdx:numel(rpmLocal);

            if numel(lastPart) < 3
                lastPart = max(1, round(0.70 * numel(rpmLocal))) : numel(rpmLocal);
            end

            ess = mean(abs(targetRPM - rpmLocal(lastPart))) / max(targetRPM, eps);
            ripple = std(rpmLocal(lastPart)) / max(targetRPM, eps);

            if numel(pwmLocal) > 1
                pwmMove = mean(abs(diff(pwmLocal))) / 255;
            else
                pwmMove = 1;
            end

            overshoot = max(0, (max(rpmLocal) - targetRPM) / max(targetRPM, eps));

            idx10 = find(rpmLocal >= 0.10 * targetRPM, 1, 'first');
            idx90 = find(rpmLocal >= 0.90 * targetRPM, 1, 'first');

            if isempty(idx10) || isempty(idx90) || idx90 <= idx10
                riseNorm = 1;
            else
                riseTime = tLocal(idx90) - tLocal(idx10);
                riseNorm = riseTime / max(app.optTestSec, eps);
            end

            J = (0.35 * ITAE_norm) + ...
                (0.25 * ess) + ...
                (0.20 * ripple) + ...
                (0.10 * overshoot) + ...
                (0.05 * riseNorm) + ...
                (0.05 * pwmMove);

            if max(rpmLocal) > 1.35 * targetRPM
                J = J * 4;
            end

            if rpmLocal(end) < 0.50 * targetRPM
                J = J * 4;
            end

            if ~isfinite(J)
                J = 1e8;
            end
        end

        function stopMotorOptimization(app)

            % Set stop flag (this is what actually stops the algorithm loop)
            app.optStopFlag = true;

            % Turn off optimizing state
            app.Optimizing = false;

            % Stop motor immediately
            app.applyMotorPWM(0);

            % Reset UI buttons
            app.StartButton.Enable = "on";
            app.StopButton_2.Enable = "off";

            if app.hwConnected
                app.RunButton.Enable = "on";
            else
                app.RunButton.Enable = "off";
            end

            % Reset labels
            app.ActualRPMLabel.Text = "Actual: -- RPM";
            app.ErrorLabel.Text     = "Error: -- RPM";
            app.PWMLabel.Text       = "PWM: 0 / 255";

            drawnow;

        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.TsTarget = app.SAMPLE_MS / 1000;

            % Derived CPR
            if app.RPM_AT_OUTPUT
                app.CPR_TOTAL = app.CPR_MOTOR_4X * app.GEAR_RATIO;
            else
                app.CPR_TOTAL = app.CPR_MOTOR_4X;
            end

            % Optimization test duration
            app.optTestSec = 5.0;

            % --- RPM target spinner ---
            app.RPMTargetSpinner.Limits = [0 80];
            app.RPMTargetSpinner.Step   = 1;
            app.RPMTargetSpinner.Value  = app.SETPOINT_RPM;
            app.SETPOINT_RPM            = app.RPMTargetSpinner.Value;

            % --- PID spinners linked to internal variables ---
            app.KpSpinner.Step  = 0.1;
            app.KiSpinner.Step  = 0.05;
            app.KdSpinner.Step  = 0.01;

            app.KpSpinner.Value = app.Kp;
            app.KiSpinner.Value = app.Ki;
            app.KdSpinner.Value = app.Kd;

            % Direction from UI
            app.forwardDir = strcmpi(app.DirectionDropDown.Value,"Forward");

            % Text indicators init
            app.ActualRPMLabel.Text = "Actual: -- RPM";
            app.ErrorLabel.Text     = "Error: -- RPM";
            app.PWMLabel.Text       = "PWM: -- / 255";

            % Start NOT connected
            app.hwConnected = false;
            app.a   = [];
            app.enc = [];
            app.lastCount = 0;

            % Create timer, but do not start it
            app.tmr = timer( ...
                "ExecutionMode","fixedRate", ...
                "Period", app.TsTarget, ...
                "BusyMode","drop", ...
                "TimerFcn", @(~,~) app.controlStep() );

            % Initialize Connection tab UI
            app.initConnectionTab();

            app.RunButton.Enable  = "off";
            app.StopButton.Enable = "off";
            app.SaveDataButton.Enable = "off";

            app.updatePidSpinnerState();

            % Plot buffers
            app.tBuf      = nan(1, app.bufMax);
            app.targetBuf = nan(1, app.bufMax);
            app.rpmBuf    = nan(1, app.bufMax);
            app.errBuf    = nan(1, app.bufMax);
            app.pwmBuf    = nan(1, app.bufMax);

            % Main motor control axes
            cla(app.UIAxes);
            grid(app.UIAxes, "on");
            app.UIAxes.XLabel.String = "Time (s)";
            app.UIAxes.YLabel.String = "RPM / PWM";

            hold(app.UIAxes, "on");
            app.lineTarget = plot(app.UIAxes, app.tBuf, app.targetBuf, "-");
            app.lineRPM    = plot(app.UIAxes, app.tBuf, app.rpmBuf, "-");
            app.lineErr    = plot(app.UIAxes, app.tBuf, app.errBuf, "-");
            app.linePWM    = plot(app.UIAxes, app.tBuf, app.pwmBuf, "-");
            legend(app.UIAxes, {"Target","Actual RPM","Error","PWM"}, "Location","northeast");
            hold(app.UIAxes, "off");

            % Optimizer settings
            app.NumberofStarsSpinner.Limits = [1 Inf];
            app.NumberofStarsSpinner.Step = 1;
            app.NumberofStarsSpinner.Value = 5;

            app.IterationsSpinner_2.Limits = [1 Inf];
            app.IterationsSpinner_2.Step = 1;
            app.IterationsSpinner_2.Value = 5;

            app.MinKpSpinner.Value = 0;
            app.MinKiSpinner.Value = 0;
            app.MinKdSpinner.Value = 0;

            app.MaxKpSpinner.Value = 10;
            app.MaxKiSpinner.Value = 10;
            app.MaxKdSpinner.Value = 10;

            app.StartButton.Enable = "off";
            app.StopButton_2.Enable = "off";

            app.UITable.Data = table([], [], [], [], [], ...
                'VariableNames', {'Iteration','Kp','Ki','Kd','Cost'});

            cla(app.UIAxes_2);
            grid(app.UIAxes_2, "on");
            app.UIAxes_2.XLabel.String = "Time (s)";
            app.UIAxes_2.YLabel.String = "RPM / PWM";
            title(app.UIAxes_2, "Motor Response");
        end

        % Value changed function: DirectionDropDown
        function DirectionDropDownValueChanged(app, event)
            app.forwardDir = strcmpi(app.DirectionDropDown.Value, "Forward");

            if app.Running
                app.applyMotorPWM(0);
                pause(0.05);
            end
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            if app.Optimizing
                uialert(app.UIFigure, ...
                    "Motor optimization is running. Stop it or wait until it finishes.", ...
                    "Motor Busy");
                return;
            end

            if ~app.hwConnected
                fprintf(2,"[WARN] Cannot run: Arduino not connected.\n");
                return;
            end

            if app.Running
                return;
            end

            % Re-enable BTS7960 after hard stop
            try
                writeDigitalPin(app.a, app.R_EN, 1);
                writeDigitalPin(app.a, app.L_EN, 1);
                writePWMDutyCycle(app.a, app.R_PWM, 0);
                writePWMDutyCycle(app.a, app.L_PWM, 0);
            catch
            end

            app.stopFlag = false;
            app.Running = true;

            app.SaveDataButton.Enable = "on";
            app.updatePidSpinnerState();

            app.t0 = tic;
            app.tPrev = toc(app.t0);

            app.firstSample = true;
            app.integral = 0;
            app.prevError = 0;
            app.dFiltered = 0;
            app.rpmFilt = 0;
            app.prevRPMFilt = 0;

            app.saveEndIndex = NaN;

            if app.hwConnected && ~isempty(app.enc)
                app.lastCount = readCount(app.enc);
            else
                app.lastCount = 0;
            end

            app.RunButton.Enable = "off";
            app.StopButton.Enable = "on";
            app.StartButton.Enable = "off";

            app.clearPlots();

            app.lastPWM = 0;
            app.controlPlotCounter = 0;

            pause(0.05);

            start(app.tmr);
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            app.stopNow();
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.StopButton.Enable = "off";
            drawnow limitrate;

            app.stopNow();

            delete(app.UIFigure);
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            app.connectArduino();
        end

        % Button pushed function: DisconnectButton
        function DisconnectButtonPushed(app, event)
            app.disconnectArduino();
        end

        % Button pushed function: TestBlinkD13Button
        function TestBlinkD13ButtonPushed(app, event)
            app.testConnection();
        end

        % Button pushed function: RefreshButton
        function RefreshButtonPushed(app, event)
            app.refreshPorts();
        end

        % Value changed function: RPMTargetSpinner
        function RPMTargetSpinnerValueChanged(app, event)
            app.SETPOINT_RPM = app.RPMTargetSpinner.Value;
            app.SETPOINT_RPM = max(0, app.SETPOINT_RPM);

            % Bumpless transfer when target changes during running
            app.integral = 0;
            app.dFiltered = 0;
            app.prevError = app.SETPOINT_RPM - app.rpmFilt;
            app.prevRPMFilt = app.rpmFilt;

            if app.SETPOINT_RPM < 0.5
                app.integral  = 0;
                app.prevError = 0;
                app.dFiltered = 0;
                app.SETPOINT_RPM = 0;
            end
        end

        % Value changed function: KpSpinner
        function KpSpinnerValueChanged(app, event)
            if app.Running
                app.KpSpinner.Value = app.Kp;
                return;
            end

            app.Kp = app.KpSpinner.Value;
        end

        % Value changed function: KiSpinner
        function KiSpinnerValueChanged(app, event)
            if app.Running
                app.KiSpinner.Value = app.Ki;
                return;
            end

            app.Ki = app.KiSpinner.Value;
        end

        % Value changed function: KdSpinner
        function KdSpinnerValueChanged(app, event)
            if app.Running
                app.KdSpinner.Value = app.Kd;
                return;
            end

            app.Kd = app.KdSpinner.Value;
        end

        % Button pushed function: SaveDataButton
        function SaveDataButtonPushed(app, event)
            app.saveResults();
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            app.startMotorOptimization();
        end

        % Button pushed function: StopButton_2
        function StopButton_2Pushed(app, event)
            app.stopMotorOptimization();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ConnectionTab = uitab(app.TabGroup);
            app.ConnectionTab.Title = 'Connection';
            app.StatusPanel = uipanel(app.ConnectionTab);
            app.StatusPanel.Title = 'Status';
            app.StatusNotconnectedLabel = uilabel(app.StatusPanel);
            app.StatusNotconnectedLabel.Text = 'Status: Not connected';
            app.StatusLampLabel = uilabel(app.StatusPanel);
            app.StatusLampLabel.Text = 'Status Lamp';
            app.StatusLamp = uilamp(app.StatusPanel);
            app.ConnectionPanel = uipanel(app.ConnectionTab);
            app.ConnectionPanel.Title = 'Connection Panel';
            app.ConnectButton = uibutton(app.ConnectionPanel, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.DisconnectButton = uibutton(app.ConnectionPanel, 'push');
            app.DisconnectButton.ButtonPushedFcn = createCallbackFcn(app, @DisconnectButtonPushed, true);
            app.DisconnectButton.Text = 'Disconnect';
            app.TestingPanel = uipanel(app.ConnectionTab);
            app.TestingPanel.Title = 'Testing Panel';
            app.RefreshButton = uibutton(app.TestingPanel, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
            app.RefreshButton.Text = 'Refresh';
            app.TestBlinkD13Button = uibutton(app.TestingPanel, 'push');
            app.TestBlinkD13Button.ButtonPushedFcn = createCallbackFcn(app, @TestBlinkD13ButtonPushed, true);
            app.TestBlinkD13Button.Text = 'Test (Blink D13)';
            app.ConfigrationPanel = uipanel(app.ConnectionTab);
            app.ConfigrationPanel.Title = 'Configration Panel';
            app.SelectYourPortLabel = uilabel(app.ConfigrationPanel);
            app.SelectYourPortLabel.Text = 'Select Your Port:';
            app.SelectYourBoardLabel = uilabel(app.ConfigrationPanel);
            app.SelectYourBoardLabel.Text = 'Select Your Board:';
            app.AvailablePortsDropDownLabel = uilabel(app.ConfigrationPanel);
            app.AvailablePortsDropDownLabel.Text = 'Available Ports:';
            app.AvailablePortsDropDown = uidropdown(app.ConfigrationPanel);
            app.AvailablePortsDropDown.Items = {'COM7', 'COM8', 'COM5', '/dev/ttyUSB0'};
            app.AvailablePortsDropDown.Editable = 'on';
            app.AvailablePortsDropDown.Value = 'COM5';
            app.BoardLabel = uilabel(app.ConfigrationPanel);
            app.BoardLabel.Text = 'Boards';
            app.BoardsDropDown = uidropdown(app.ConfigrationPanel);
            app.BoardsDropDown.Items = {'Nano3', 'Uno'};
            app.BoardsDropDown.Editable = 'on';
            app.BoardsDropDown.Value = 'Nano3';
            app.MotorOptimizingTab = uitab(app.TabGroup);
            app.MotorOptimizingTab.Title = 'Motor Optimizing';
            app.SetPIDLimitsPanel = uipanel(app.MotorOptimizingTab);
            app.SetPIDLimitsPanel.Title = 'Set PID Limits';
            app.MinKpSpinnerLabel = uilabel(app.SetPIDLimitsPanel);
            app.MinKpSpinnerLabel.Text = 'Min Kp';
            app.MinKpSpinner = uispinner(app.SetPIDLimitsPanel);
            app.MinKiSpinnerLabel = uilabel(app.SetPIDLimitsPanel);
            app.MinKiSpinnerLabel.Text = 'Min Ki';
            app.MinKiSpinner = uispinner(app.SetPIDLimitsPanel);
            app.MinKdSpinnerLabel = uilabel(app.SetPIDLimitsPanel);
            app.MinKdSpinnerLabel.Text = 'Min Kd';
            app.MinKdSpinner = uispinner(app.SetPIDLimitsPanel);
            app.MaxKdSpinnerLabel = uilabel(app.SetPIDLimitsPanel);
            app.MaxKdSpinnerLabel.Text = 'Max Kd';
            app.MaxKdSpinner = uispinner(app.SetPIDLimitsPanel);
            app.MaxKdSpinner.Value = 10;
            app.MaxKiSpinnerLabel = uilabel(app.SetPIDLimitsPanel);
            app.MaxKiSpinnerLabel.Text = 'Max Ki';
            app.MaxKiSpinner = uispinner(app.SetPIDLimitsPanel);
            app.MaxKiSpinner.Value = 10;
            app.MaxKpSpinnerLabel = uilabel(app.SetPIDLimitsPanel);
            app.MaxKpSpinnerLabel.Text = 'Max Kp';
            app.MaxKpSpinner = uispinner(app.SetPIDLimitsPanel);
            app.MaxKpSpinner.Value = 10;
            app.OptimizorSettingsPanel = uipanel(app.MotorOptimizingTab);
            app.OptimizorSettingsPanel.Title = 'Optimizor Settings';
            app.NumberofStarsSpinnerLabel = uilabel(app.OptimizorSettingsPanel);
            app.NumberofStarsSpinnerLabel.Text = 'Number of Stars';
            app.NumberofStarsSpinner = uispinner(app.OptimizorSettingsPanel);
            app.NumberofStarsSpinner.Limits = [0 Inf];
            app.IterationsSpinner_2Label = uilabel(app.OptimizorSettingsPanel);
            app.IterationsSpinner_2Label.Text = 'Iterations';
            app.IterationsSpinner_2 = uispinner(app.OptimizorSettingsPanel);
            app.IterationsSpinner_2.Limits = [0 Inf];
            app.StartButton = uibutton(app.OptimizorSettingsPanel, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.StopButton_2 = uibutton(app.OptimizorSettingsPanel, 'push');
            app.StopButton_2.ButtonPushedFcn = createCallbackFcn(app, @StopButton_2Pushed, true);
            app.StopButton_2.Text = 'Stop';
            app.ResultsPanel = uipanel(app.MotorOptimizingTab);
            app.ResultsPanel.Title = 'Results';
            app.UIAxes_2 = uiaxes(app.ResultsPanel);
            title(app.UIAxes_2, 'Motor Response')
            xlabel(app.UIAxes_2, 'Time')
            ylabel(app.UIAxes_2, 'RPM/PWM')
            zlabel(app.UIAxes_2, 'Z')
            app.UITable = uitable(app.ResultsPanel);
            app.UITable.ColumnName = {'Iteration'; 'Kp'; 'Ki'; 'Kd'; 'Cost'};
            app.UITable.RowName = {};
            app.MotorControlTab = uitab(app.TabGroup);
            app.MotorControlTab.Title = 'Motor Control';
            app.SystemControllingPanel = uipanel(app.MotorControlTab);
            app.SystemControllingPanel.Title = 'System Controlling';
            app.KpSpinnerLabel = uilabel(app.SystemControllingPanel);
            app.KpSpinnerLabel.Text = 'Kp';
            app.KpSpinner = uispinner(app.SystemControllingPanel);
            app.KpSpinner.ValueChangedFcn = createCallbackFcn(app, @KpSpinnerValueChanged, true);
            app.KiSpinnerLabel = uilabel(app.SystemControllingPanel);
            app.KiSpinnerLabel.Text = 'Ki';
            app.KiSpinner = uispinner(app.SystemControllingPanel);
            app.KiSpinner.ValueChangedFcn = createCallbackFcn(app, @KiSpinnerValueChanged, true);
            app.KdSpinnerLabel = uilabel(app.SystemControllingPanel);
            app.KdSpinnerLabel.Text = 'Kd';
            app.KdSpinner = uispinner(app.SystemControllingPanel);
            app.KdSpinner.ValueChangedFcn = createCallbackFcn(app, @KdSpinnerValueChanged, true);
            app.RPMTargetSpinnerLabel = uilabel(app.SystemControllingPanel);
            app.RPMTargetSpinnerLabel.Text = 'RPM Target';
            app.RPMTargetSpinner = uispinner(app.SystemControllingPanel);
            app.RPMTargetSpinner.Limits = [0 80];
            app.RPMTargetSpinner.ValueChangedFcn = createCallbackFcn(app, @RPMTargetSpinnerValueChanged, true);
            app.DirectionDropDownLabel = uilabel(app.SystemControllingPanel);
            app.DirectionDropDownLabel.Text = 'Direction';
            app.DirectionDropDown = uidropdown(app.SystemControllingPanel);
            app.DirectionDropDown.Items = {'Forward', 'Reverse'};
            app.DirectionDropDown.ValueChangedFcn = createCallbackFcn(app, @DirectionDropDownValueChanged, true);
            app.DirectionDropDown.Value = 'Forward';
            app.RunButton = uibutton(app.SystemControllingPanel, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.StopButton = uibutton(app.SystemControllingPanel, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.SystemMonitoringPanel = uipanel(app.MotorControlTab);
            app.SystemMonitoringPanel.Title = 'System Monitoring';
            app.UIAxes = uiaxes(app.SystemMonitoringPanel);
            title(app.UIAxes, 'Motor Response')
            xlabel(app.UIAxes, 'Time')
            ylabel(app.UIAxes, 'RPM/PWM')
            zlabel(app.UIAxes, 'Z')
            app.ActualRPMLabel = uilabel(app.SystemMonitoringPanel);
            app.ActualRPMLabel.Text = 'Actual RPM';
            app.ErrorLabel = uilabel(app.SystemMonitoringPanel);
            app.ErrorLabel.Text = 'Error';
            app.PWMLabel = uilabel(app.SystemMonitoringPanel);
            app.PWMLabel.Text = 'PWM';
            app.SaveDataButton = uibutton(app.SystemMonitoringPanel, 'push');
            app.SaveDataButton.ButtonPushedFcn = createCallbackFcn(app, @SaveDataButtonPushed, true);
            app.SaveDataButton.Text = 'Save Data';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Controlling_DC_motor_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end