classdef BHA_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        SaveResultsButton               matlab.ui.control.Button
        ResultsPanelButton              matlab.ui.control.Button
        ExitResultsPanelButton          matlab.ui.control.Button
        ResultsPanel                    matlab.ui.container.Panel
        ResultsTable                    matlab.ui.control.Table
        System_Response                 matlab.ui.control.UIAxes
        Cost                            matlab.ui.control.UIAxes
        StatusPanel                     matlab.ui.container.Panel
        Image3                          matlab.ui.control.Image
        Image2                          matlab.ui.control.Image
        Status                          matlab.ui.control.Label
        Image1                          matlab.ui.control.Image
        ParametersPanel                 matlab.ui.container.Panel
        CustomTFPanel                   matlab.ui.container.Panel
        StepAmplitudeCustomSpinner      matlab.ui.control.Spinner
        StepAmplitudeLabel_2            matlab.ui.control.Label
        DenominatorCoefficientsEditField  matlab.ui.control.EditField
        DenominatorCoefficientsEditFieldLabel  matlab.ui.control.Label
        NumeratorCoefficientsEditField  matlab.ui.control.EditField
        NumeratorCoefficientsEditFieldLabel  matlab.ui.control.Label
        CustomTFAxes                    matlab.ui.control.UIAxes
        BandBParametersPanel            matlab.ui.container.Panel
        J_b_and_bSpinner                matlab.ui.control.Spinner
        JSpinnerLabel                   matlab.ui.control.Label
        StepAmplitude_b_and_bSpinner    matlab.ui.control.Spinner
        StepAmplitudeLabel              matlab.ui.control.Label
        L_b_and_bSpinner                matlab.ui.control.Spinner
        LSpinnerLabel                   matlab.ui.control.Label
        R_b_and_bSpinner                matlab.ui.control.Spinner
        RSpinnerLabel                   matlab.ui.control.Label
        d_b_and_bSpinner                matlab.ui.control.Spinner
        dSpinnerLabel                   matlab.ui.control.Label
        g_b_and_bSpinner                matlab.ui.control.Spinner
        gSpinnerLabel                   matlab.ui.control.Label
        m_b_and_bSpinner                matlab.ui.control.Spinner
        mSpinnerLabel                   matlab.ui.control.Label
        MotorParametersPanel            matlab.ui.container.Panel
        RPM_motorSpinner                matlab.ui.control.Spinner
        RPMSpinnerLabel                 matlab.ui.control.Label
        K_motorSpinner                  matlab.ui.control.Spinner
        KSpinnerLabel                   matlab.ui.control.Label
        b_motorSpinner                  matlab.ui.control.Spinner
        bSpinner_2Label                 matlab.ui.control.Label
        J_motorSpinner                  matlab.ui.control.Spinner
        mSpinner_2Label_2               matlab.ui.control.Label
        L_motorSpinner                  matlab.ui.control.Spinner
        LSpinner_2Label                 matlab.ui.control.Label
        R_motorSpinner                  matlab.ui.control.Spinner
        RSpinner_2Label                 matlab.ui.control.Label
        CruiseParametersPanel           matlab.ui.container.Panel
        Speed_cruiseSpinner             matlab.ui.control.Spinner
        SpeedSpinnerLabel               matlab.ui.control.Label
        b_cruiseSpinner                 matlab.ui.control.Spinner
        bSpinnerLabel                   matlab.ui.control.Label
        m_cruiseSpinner                 matlab.ui.control.Spinner
        mSpinner_2Label                 matlab.ui.control.Label
        OptimizationSettingsPanel       matlab.ui.container.Panel
        MinKpSpinner                    matlab.ui.control.Spinner
        MinKpSpinnerLabel               matlab.ui.control.Label
        MinKiSpinner                    matlab.ui.control.Spinner
        MinKiSpinnerLabel               matlab.ui.control.Label
        MinKdSpinner                    matlab.ui.control.Spinner
        MinKdSpinnerLabel               matlab.ui.control.Label
        WantedSteadyStateErrorSpinner   matlab.ui.control.Spinner
        WantedSteadyStateErrorLabel     matlab.ui.control.Label
        WantedRiseTimesSpinner          matlab.ui.control.Spinner
        WantedRiseTimesSpinnerLabel     matlab.ui.control.Label
        WantedOvershootSpinner          matlab.ui.control.Spinner
        WantedOvershootLabel            matlab.ui.control.Label
        BestOptimizationModeCheckBox    matlab.ui.control.CheckBox
        StopButton                      matlab.ui.control.Button
        SimulationTimeSpinner           matlab.ui.control.Spinner
        SimulationTimeSpinnerLabel      matlab.ui.control.Label
        RunButton                       matlab.ui.control.Button
        MaxKdSpinner                    matlab.ui.control.Spinner
        MaxKdSpinnerLabel               matlab.ui.control.Label
        MaxKiSpinner                    matlab.ui.control.Spinner
        MaxKiSpinnerLabel               matlab.ui.control.Label
        MaxKpSpinner                    matlab.ui.control.Spinner
        MaxKpSpinnerLabel               matlab.ui.control.Label
        MaxIterationsSpinner            matlab.ui.control.Spinner
        MaxIterationsSpinnerLabel       matlab.ui.control.Label
        PopulationNumberofStarsSpinner  matlab.ui.control.Spinner
        PopulationNumberofStarsLabel    matlab.ui.control.Label
        SystemSelectionPanel            matlab.ui.container.Panel
        TypeofControllerDropDown        matlab.ui.control.DropDown
        TypeofControllerDropDownLabel   matlab.ui.control.Label
        SelectSystemDropDown            matlab.ui.control.DropDown
        SelectSystemDropDownLabel       matlab.ui.control.Label
        BlackHoleOptimizerLabel         matlab.ui.control.Label
    end


    properties (Access = private)
        ParamLabels = matlab.ui.control.Label.empty
        ParamFields = matlab.ui.control.NumericEditField.empty
        ParamNames cell = {}
        IsRunning = false
        StopRequested = false
        CurrentModel = ""
        LastRunCompleted = false
        LastSystemName = ""
        LastControllerType = ""
        LastResultsFolder = ""
    end

    methods (Access = private)

        function hideAllStatusImages(app)
            % Hide all status images
            app.Image1.Visible = 'off';
            app.Image2.Visible = 'off';
            app.Image3.Visible = 'off';
            drawnow;
        end

        function showStatusImage(app, imgNum)
            % Show only one status image
            app.hideAllStatusImages();

            switch imgNum
                case 1
                    app.Image1.Visible = 'on';
                case 2
                    app.Image2.Visible = 'on';
                case 3
                    app.Image3.Visible = 'on';
            end

            drawnow;
        end

        function restartImage1Gif(app)
            % Restart Image1 GIF from the beginning
            gifSource = app.Image1.ImageSource;

            app.Image1.Visible = 'off';
            drawnow;

            app.Image1.ImageSource = '';
            drawnow;
            pause(0.05);

            app.Image1.ImageSource = gifSource;
            drawnow;
        end

        function playStage1ThenStage2(app)
            % Play Image1 for a fixed time and allow stop during playback
            app.throwIfStopped();

            app.restartImage1Gif();
            app.showStatusImage(1);

            tStart = tic;
            while toc(tStart) < 6
                drawnow;
                app.throwIfStopped();
                pause(0.05);
            end

            app.throwIfStopped();
            app.showStatusImage(2);
        end

        function [VarMin, VarMax, gainNames] = getControllerInfo(app)

            controllerType = app.TypeofControllerDropDown.Value;

            minKp = app.MinKpSpinner.Value;
            minKi = app.MinKiSpinner.Value;
            minKd = app.MinKdSpinner.Value;

            maxKp = app.MaxKpSpinner.Value;
            maxKi = app.MaxKiSpinner.Value;
            maxKd = app.MaxKdSpinner.Value;

            if minKp < 0 || minKi < 0 || minKd < 0
                error('Minimum controller gains cannot be negative.');
            end

            if maxKp <= 0
                error('Max Kp must be greater than zero.');
            end

            if minKp > maxKp
                error('Min Kp must be less than or equal to Max Kp.');
            end

            switch upper(controllerType)

                case 'PI'
                    if maxKi <= 0
                        error('Max Ki must be greater than zero for PI.');
                    end

                    if minKi > maxKi
                        error('Min Ki must be less than or equal to Max Ki for PI.');
                    end

                    VarMin = [minKp minKi];
                    VarMax = [maxKp maxKi];
                    gainNames = {'Kp','Ki'};

                case 'PD'
                    if maxKd <= 0
                        error('Max Kd must be greater than zero for PD.');
                    end

                    if minKd > maxKd
                        error('Min Kd must be less than or equal to Max Kd for PD.');
                    end

                    VarMin = [minKp minKd];
                    VarMax = [maxKp maxKd];
                    gainNames = {'Kp','Kd'};

                case 'PID'
                    if maxKi <= 0 || maxKd <= 0
                        error('Max Ki and Max Kd must be greater than zero for PID.');
                    end

                    if minKi > maxKi
                        error('Min Ki must be less than or equal to Max Ki for PID.');
                    end

                    if minKd > maxKd
                        error('Min Kd must be less than or equal to Max Kd for PID.');
                    end

                    VarMin = [minKp minKi minKd];
                    VarMax = [maxKp maxKi maxKd];
                    gainNames = {'Kp','Ki','Kd'};

                otherwise
                    error('Unknown controller type.');
            end
        end

        function [model, initFcn, costFcnRaw, params] = getSelectedSystemData(app)

            systemName = app.SelectSystemDropDown.Value;
            controllerType = upper(app.TypeofControllerDropDown.Value);

            switch systemName
                case 'Ball & Beam'
                    initFcn = @init_ballandbeam;

                    switch controllerType
                        case 'PI'
                            model = "BallandBeamPI";
                        case 'PD'
                            model = "BallandBeamPD";
                        case 'PID'
                            model = "BallandBeamPID";
                        otherwise
                            error('Invalid controller type.');
                    end

                    costFcnRaw = @(K) cost_ballandbeam(K, model);

                    params.m        = app.m_b_and_bSpinner.Value;
                    params.R        = app.R_b_and_bSpinner.Value;
                    params.g        = app.g_b_and_bSpinner.Value;
                    params.L        = app.L_b_and_bSpinner.Value;
                    params.d        = app.d_b_and_bSpinner.Value;
                    params.J        = app.J_b_and_bSpinner.Value;
                    params.step_amp = app.StepAmplitude_b_and_bSpinner.Value;

                case 'Cruise Control'
                    initFcn = @init_cruise;

                    switch controllerType
                        case 'PI'
                            model = "CruiseControlPI";
                        case 'PD'
                            model = "CruiseControlPD";
                        case 'PID'
                            model = "CruiseControlPID";
                        otherwise
                            error('Invalid controller type.');
                    end

                    costFcnRaw = @(K) cost_cruise(K, model);

                    params.m        = app.m_cruiseSpinner.Value;
                    params.b        = app.b_cruiseSpinner.Value;
                    params.step_amp = app.Speed_cruiseSpinner.Value;

                case 'Motor Speed Control'
                    initFcn = @init_motor;

                    switch controllerType
                        case 'PI'
                            model = "MotorSpeedPI";
                        case 'PD'
                            model = "MotorSpeedPD";
                        case 'PID'
                            model = "MotorSpeedPID";
                        otherwise
                            error('Invalid controller type.');
                    end

                    costFcnRaw = @(K) cost_motor(K, model);

                    params.J        = app.J_motorSpinner.Value;
                    params.b        = app.b_motorSpinner.Value;
                    params.K        = app.K_motorSpinner.Value;
                    params.R        = app.R_motorSpinner.Value;
                    params.L        = app.L_motorSpinner.Value;
                    params.step_amp = app.RPM_motorSpinner.Value;

                case 'Custom TF'
                    switch controllerType
                        case 'PI'
                            model = "CustomSystemPI";
                        case 'PD'
                            model = "CustomSystemPD";
                        case 'PID'
                            model = "CustomSystemPID";
                        otherwise
                            error('Invalid controller type.');
                    end

                    num = str2double(strsplit(strtrim(app.NumeratorCoefficientsEditField.Value)));
                    den = str2double(strsplit(strtrim(app.DenominatorCoefficientsEditField.Value)));

                    if any(isnan(num)) || any(isnan(den)) || isempty(num) || isempty(den)
                        error('Custom TF coefficients are invalid.');
                    end

                    if ~isvector(num) || ~isvector(den)
                        error('Custom TF numerator and denominator must be vectors.');
                    end

                    if all(num == 0)
                        error('Custom TF numerator cannot be all zeros.');
                    end

                    if all(den == 0)
                        error('Custom TF denominator cannot be all zeros.');
                    end

                    params.num      = num(:).';
                    params.den      = den(:).';
                    params.step_amp = app.StepAmplitudeCustomSpinner.Value;

                    initFcn = @() [];
                    costFcnRaw = @(K) cost_custom(K, model);

                otherwise
                    error('Please select a system first.');
            end

        end

        function Kfull = expandControllerGains(app, Kopt, controllerType)

            switch upper(controllerType)
                case 'PI'
                    Kfull = [Kopt(1) Kopt(2) 0];

                case 'PD'
                    Kfull = [Kopt(1) 0 Kopt(2)];

                case 'PID'
                    Kfull = [Kopt(1) Kopt(2) Kopt(3)];

                otherwise
                    error('Invalid controller type.');
            end
        end

        function cost = controllerCostWrapper(app, Kopt, controllerType, costFcnRaw)
            % Stop-aware controller wrapper with immediate UI responsiveness
            app.throwIfStopped();

            Kfull = app.expandControllerGains(Kopt, controllerType);
            cost = costFcnRaw(Kfull);

            app.throwIfStopped();
        end


        function assignSystemParamsToBase(app, params)

            names = fieldnames(params);

            for i = 1:numel(names)
                assignin('base', names{i}, params.(names{i}));
            end
        end


        function tf = isStopRequested(app)
            % Process pending UI callbacks and return stop flag
            drawnow;
            tf = app.StopRequested;
        end

        function throwIfStopped(app)
            % Process pending UI callbacks immediately
            drawnow;

            % Throw a dedicated stop error when user requested stop
            if app.StopRequested
                error('BHA_App:StoppedByUser', 'Process stopped by user.');
            end
        end

        function stopActiveSimulation(app)
            % Try to stop any currently running Simulink model
            try
                if strlength(app.CurrentModel) > 0 && bdIsLoaded(char(app.CurrentModel))
                    simStatus = get_param(char(app.CurrentModel), 'SimulationStatus');

                    if any(strcmpi(simStatus, {'running','paused','initializing','updating'}))
                        set_param(char(app.CurrentModel), 'SimulationCommand', 'stop');
                    end
                end
            catch
                % Do nothing if the model is not active
            end
        end

        function resetRunState(app)
            % Restore UI state after finish/stop/error
            app.IsRunning = false;
            app.StopRequested = false;
            app.CurrentModel = "";

            app.RunButton.Enable = 'on';
            app.StopButton.Enable = 'off';
            app.UIFigure.Pointer = 'arrow';
            app.Status.FontColor = [0 0 0];
            drawnow;
        end


        function resizeHeaderLabel(app)
            % Resize the top header label to always match the figure width
            figPos = app.UIFigure.Position;
            app.BlackHoleOptimizerLabel.Position = [1 figPos(4)-40 figPos(3) 41];
        end


        function updateOptimizationModeUI(app)
            % Update UI based on Best Optimization Mode checkbox state

            isBestMode = app.BestOptimizationModeCheckBox.Value;

            if isBestMode
                app.WantedOvershootLabel.Enable = 'off';
                app.WantedOvershootSpinner.Enable = 'off';

                app.WantedRiseTimesSpinnerLabel.Enable = 'off';
                app.WantedRiseTimesSpinner.Enable = 'off';

                app.WantedSteadyStateErrorLabel.Enable = 'off';
                app.WantedSteadyStateErrorSpinner.Enable = 'off';
            else
                app.WantedOvershootLabel.Enable = 'on';
                app.WantedOvershootSpinner.Enable = 'on';

                app.WantedRiseTimesSpinnerLabel.Enable = 'on';
                app.WantedRiseTimesSpinner.Enable = 'on';

                app.WantedSteadyStateErrorLabel.Enable = 'on';
                app.WantedSteadyStateErrorSpinner.Enable = 'on';
            end

            drawnow;
        end

        function resetBaseWorkspaceForRun(app)
            % Clear old project variables from base workspace before each run

            varsToClear = { ...
                'kp', 'ki', 'kd', ...
                'num', 'den', ...
                'm', 'b', 'J', 'K', 'R', 'L', 'g', 'd', ...
                'step_amp', 'ref', ...
                'sim_time', 'optimization_mode', ...
                'wantedovershoot', 'wantedrisetime', 'wantedess'};

            for i = 1:numel(varsToClear)
                if evalin('base', sprintf('exist(''%s'',''var'')', varsToClear{i}))
                    evalin('base', sprintf('clear(''%s'')', varsToClear{i}));
                end
            end
        end

        function updateLiveOptimizationDisplay(app, history)
            % Update table and cost plot live after each iteration

            app.throwIfStopped();

            % Update cost plot
            cla(app.Cost);

            if ~isempty(history) && isfield(history, 'Iteration') && ~isempty(history.Iteration)
                iter = history.Iteration(:);
                costVals = history.Cost(:);

                plot(app.Cost, iter, costVals, '-o', 'LineWidth', 2, 'MarkerSize', 6);
                grid(app.Cost, 'on');
                xlabel(app.Cost, 'Iteration');
                ylabel(app.Cost, 'Cost');
                title(app.Cost, 'Cost per Iteration');

                if numel(iter) == 1
                    xlim(app.Cost, [1 2]);
                else
                    xlim(app.Cost, [1 max(iter)]);
                end
            end

            % Update table
            app.ResultsTable.ColumnName = { ...
                'Iteration', 'Kp', 'Ki', 'Kd', ...
                'Cost', 'Overshoot', 'Rise Time', 'Steady-State Error'};
            app.ResultsTable.RowName = {};

            if ~isempty(history) && isfield(history, 'Iteration') && ~isempty(history.Iteration)
                nRows = numel(history.Iteration);
                tableData = cell(nRows, 8);

                for i = 1:nRows
                    tableData{i, 1} = history.Iteration(i);
                    tableData{i, 2} = sprintf('%.4f', history.Kp(i));
                    tableData{i, 3} = sprintf('%.4f', history.Ki(i));
                    tableData{i, 4} = sprintf('%.4f', history.Kd(i));
                    tableData{i, 5} = sprintf('%.6f', history.Cost(i));

                    if isnan(history.Overshoot(i))
                        tableData{i, 6} = '-';
                    else
                        tableData{i, 6} = sprintf('%.2f%%', history.Overshoot(i));
                    end

                    if isnan(history.RiseTime(i))
                        tableData{i, 7} = '-';
                    else
                        tableData{i, 7} = sprintf('%.4f s', history.RiseTime(i));
                    end

                    if isnan(history.ESS(i))
                        tableData{i, 8} = '-';
                    else
                        tableData{i, 8} = sprintf('%.2f%%', history.ESS(i));
                    end
                end

                app.ResultsTable.Data = tableData;
            else
                app.ResultsTable.Data = {};
            end

            app.ResultsTable.ColumnWidth = 'auto';
            drawnow;
        end

        function polyStr = buildPolynomial(app, coeffs)

            n = length(coeffs);
            polyStr = "";

            for i = 1:n
                c = coeffs(i);
                power = n - i;

                if c == 0
                    continue;
                end

                % Sign
                if polyStr == ""
                    signStr = "";
                elseif c > 0
                    signStr = " + ";
                else
                    signStr = " - ";
                end

                c = abs(c);

                % Term
                if power == 0
                    term = sprintf('%.3g', c);
                elseif power == 1
                    if c == 1
                        term = 's';
                    else
                        term = sprintf('%.3gs', c);
                    end
                else
                    if c == 1
                        term = sprintf('s^{%d}', power);
                    else
                        term = sprintf('%.3gs^{%d}', c, power);
                    end
                end

                polyStr = polyStr + signStr + term;
            end

            if polyStr == ""
                polyStr = "0";
            end

        end

        function updateCustomTFPreview(app)

            try
                numStr = strtrim(app.NumeratorCoefficientsEditField.Value);
                denStr = strtrim(app.DenominatorCoefficientsEditField.Value);

                num = str2double(strsplit(numStr));
                den = str2double(strsplit(denStr));

                if any(isnan(num)) || any(isnan(den)) || isempty(num) || isempty(den)
                    error('Invalid coefficients');
                end

                numPoly = app.buildPolynomial(num);
                denPoly = app.buildPolynomial(den);

                latexStr = "$G(s)=\frac{" + numPoly + "}{" + denPoly + "}$";

                cla(app.CustomTFAxes);
                axis(app.CustomTFAxes, 'off');
                app.CustomTFAxes.XLim = [0 1];
                app.CustomTFAxes.YLim = [0 1];

                text(app.CustomTFAxes, 0.5, 0.5, latexStr, ...
                    'Interpreter', 'latex', ...
                    'HorizontalAlignment', 'center', ...
                    'VerticalAlignment', 'middle', ...
                    'FontSize', 14);

            catch
                cla(app.CustomTFAxes);
                axis(app.CustomTFAxes, 'off');
                app.CustomTFAxes.XLim = [0 1];
                app.CustomTFAxes.YLim = [0 1];

                text(app.CustomTFAxes, 0.5, 0.5, 'Invalid Input', ...
                    'HorizontalAlignment', 'center', ...
                    'VerticalAlignment', 'middle', ...
                    'FontSize', 12);
            end

        end

        function systemFolderName = getSystemFolderName(app, systemName)

            switch char(systemName)
                case 'Ball & Beam'
                    systemFolderName = 'BallAndBeam';

                case 'Cruise Control'
                    systemFolderName = 'CruiseControl';

                case 'Motor Speed Control'
                    systemFolderName = 'MotorSpeedControl';

                case 'Custom TF'
                    systemFolderName = 'CustomTF';

                otherwise
                    systemFolderName = regexprep(char(systemName), '[^\w]', '');
                    if isempty(systemFolderName)
                        systemFolderName = 'UnknownSystem';
                    end
            end

        end

        function nextTestNumber = getNextTestNumber(app, saveFolder, systemFolderName, controllerFolderName)

            pattern = fullfile(saveFolder, sprintf('%s_%s_test*_Table.xlsx', systemFolderName, controllerFolderName));
            files = dir(pattern);

            maxTest = 0;

            for k = 1:numel(files)
                fileName = files(k).name;

                token = regexp(fileName, '_test(\d+)_Table\.xlsx$', 'tokens', 'once');

                if ~isempty(token)
                    testNum = str2double(token{1});
                    if ~isnan(testNum) && testNum > maxTest
                        maxTest = testNum;
                    end
                end
            end

            nextTestNumber = maxTest + 1;

        end

        function saveAxesAsPNG(app, axHandle, filePath)

            if isempty(axHandle) || ~isvalid(axHandle)
                error('Invalid axes handle for saving image.');
            end

            drawnow;

            try
                exportgraphics(axHandle, filePath, 'Resolution', 300);
            catch
                frame = getframe(axHandle);
                imwrite(frame.cdata, filePath);
            end

        end

        function saveResultsTableToExcel(app, filePath)

            tableData = app.ResultsTable.Data;
            columnNames = app.ResultsTable.ColumnName;

            if isempty(tableData)
                error('Results table is empty. Nothing to save.');
            end

            if ischar(columnNames)
                columnNames = cellstr(columnNames);
            end

            if isstring(columnNames)
                columnNames = cellstr(columnNames);
            end

            % Ensure table data is cell
            if ~iscell(tableData)
                tableData = num2cell(tableData);
            end

            % Create valid variable names for Excel table
            validNames = matlab.lang.makeValidName(columnNames, 'ReplacementStyle', 'delete');

            % Convert cell array to table
            T = cell2table(tableData, 'VariableNames', validNames);

            writetable(T, filePath, 'FileType', 'spreadsheet');

        end

        function cleanupSimulinkArtifacts(app, mainFolder)
            % Delete incompatible Simulink generated folders from common locations

            foldersToDelete = { ...
                fullfile(mainFolder, 'slprj'), ...
                fullfile(pwd, 'slprj'), ...
                fullfile(mainFolder, 'sfprj'), ...
                fullfile(pwd, 'sfprj')};

            for i = 1:numel(foldersToDelete)
                folderPath = foldersToDelete{i};

                if exist(folderPath, 'dir')
                    try
                        rmdir(folderPath, 's');
                    catch
                        % Ignore deletion failures
                    end
                end
            end

            % Reset Simulink file generation configuration
            try
                Simulink.fileGenControl('reset');
            catch
                % Ignore reset failures
            end
        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            app.BandBParametersPanel.Visible = 'off';
            app.CruiseParametersPanel.Visible = 'off';
            app.MotorParametersPanel.Visible = 'off';
            app.CustomTFPanel.Visible = 'off';

            app.hideAllStatusImages();

            app.StopButton.Enable = 'off';
            app.SaveResultsButton.Enable = 'off';

            % Results panel state at startup
            app.ResultsPanel.Visible = 'off';
            app.ResultsPanelButton.Enable = 'on';
            app.ExitResultsPanelButton.Enable = 'on';

            app.UIFigure.AutoResizeChildren = 'on';
            app.ResultsPanel.AutoResizeChildren = 'on';
            app.StatusPanel.AutoResizeChildren = 'on';
            app.ParametersPanel.AutoResizeChildren = 'on';
            app.OptimizationSettingsPanel.AutoResizeChildren = 'on';
            app.SystemSelectionPanel.AutoResizeChildren = 'on';

            % Initial status
            app.Status.Text = 'Ready';

            % Default optimization mode = Best
            app.BestOptimizationModeCheckBox.Value = true;
            app.updateOptimizationModeUI();

            % Default Custom TF values
            app.NumeratorCoefficientsEditField.Value = '1';
            app.DenominatorCoefficientsEditField.Value = '1 5';
            app.StepAmplitudeCustomSpinner.Value = 1;

            % Prepare Custom TF preview axes
            cla(app.CustomTFAxes);
            title(app.CustomTFAxes, '');
            xlabel(app.CustomTFAxes, '');
            ylabel(app.CustomTFAxes, '');
            zlabel(app.CustomTFAxes, '');
            axis(app.CustomTFAxes, 'off');
            app.CustomTFAxes.XColor = 'none';
            app.CustomTFAxes.YColor = 'none';
            app.CustomTFAxes.Box = 'off';
            app.CustomTFAxes.XLim = [0 1];
            app.CustomTFAxes.YLim = [0 1];

            % Reset saved run state
            app.LastRunCompleted = false;
            app.LastSystemName = "";
            app.LastControllerType = "";
            app.LastResultsFolder = "";

            % Draw initial preview
            app.updateCustomTFPreview();

        end

        % Value changed function: SelectSystemDropDown
        function SelectSystemDropDownValueChanged(app, event)
            value = app.SelectSystemDropDown.Value;
            disp(value)

            % Hide all panels first
            app.BandBParametersPanel.Visible = 'off';
            app.CruiseParametersPanel.Visible = 'off';
            app.MotorParametersPanel.Visible = 'off';
            app.CustomTFPanel.Visible = 'off';

            % Show selected panel
            if strcmp(value, 'Ball & Beam')
                app.BandBParametersPanel.Visible = 'on';

            elseif strcmp(value, 'Cruise Control')
                app.CruiseParametersPanel.Visible = 'on';

            elseif strcmp(value, 'Motor Speed Control')
                app.MotorParametersPanel.Visible = 'on';

            elseif strcmp(value, 'Custom TF')
                app.CustomTFPanel.Visible = 'on';
            end
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            % Prevent double clicking and re-entry
            if app.IsRunning
                return;
            end

            % Read selections first before changing any UI state
            systemName     = app.SelectSystemDropDown.Value;
            controllerType = upper(app.TypeofControllerDropDown.Value);

            if isempty(systemName) || strcmp(systemName, '')
                uialert(app.UIFigure, 'Please select a system first.', 'Error');
                return;
            end

            % Validate basic numeric run settings before changing UI state
            nPop    = app.PopulationNumberofStarsSpinner.Value;
            MaxIt   = app.MaxIterationsSpinner.Value;
            simTime = app.SimulationTimeSpinner.Value;

            if nPop <= 0
                uialert(app.UIFigure, 'Population must be greater than zero.', 'Error');
                return;
            end

            if MaxIt <= 0
                uialert(app.UIFigure, 'Max Iterations must be greater than zero.', 'Error');
                return;
            end

            if simTime <= 0
                uialert(app.UIFigure, 'Simulation Time must be greater than zero.', 'Error');
                return;
            end

            % Compatibility check BEFORE changing status/images/results panel
            if strcmp(systemName, 'Ball & Beam') && strcmpi(controllerType, 'PI')
                uialert(app.UIFigure, ...
                    'PI controller is not suitable for the Ball and Beam system. Please choose PD or PID controller.', ...
                    'Controller Selection Error');
                return;
            end

            if strcmp(systemName, 'Cruise Control') && strcmpi(controllerType, 'PD')
                uialert(app.UIFigure, ...
                    ['PD controller may not be 100% efficient for the Cruise Control system. ', ...
                    'It may cause steady-state error and may not track the desired reference accurately.'], ...
                    'Controller Warning', 'Icon', 'warning');
            end

            % Reset save state for the new run
            app.SaveResultsButton.Enable = 'off';
            app.LastRunCompleted = false;
            app.LastSystemName = "";
            app.LastControllerType = "";
            app.LastResultsFolder = "";

            % Only after validation passes, start changing run state and UI
            app.StopRequested = false;
            app.IsRunning = true;
            app.RunButton.Enable = 'off';
            app.StopButton.Enable = 'on';
            app.UIFigure.Pointer = 'watch';
            app.Status.FontColor = [0 0 0];
            app.Status.Text = 'Algorithm started. Searching for the best solution...';

            % Show results panel when run starts
            app.ResultsPanel.Visible = 'on';

            drawnow;

            cleanupObj = onCleanup(@() resetRunState(app)); %#ok<NASGU>

            oldFolder = pwd;
            restoreFolderObj = onCleanup(@() cd(oldFolder)); %#ok<NASGU>

            try
                app.throwIfStopped();

                % Reset visuals for a fresh run
                app.hideAllStatusImages();
                cla(app.System_Response);
                cla(app.Cost);
                app.ResultsTable.Data = {};

                % Reset old workspace variables before every run
                app.resetBaseWorkspaceForRun();

                % Play status animation
                app.playStage1ThenStage2();

                app.throwIfStopped();

                % Update status before running the optimization
                app.Status.FontColor = [0 0 0];
                app.Status.Text = 'Optimization is running. Searching for the best solution...';
                drawnow;

                app.throwIfStopped();

                % Read controller bounds
                [VarMin, VarMax, ~] = app.getControllerInfo();

                % Optimization mode
                if app.BestOptimizationModeCheckBox.Value
                    optimization_mode = 1; % BEST
                else
                    optimization_mode = 2; % CONSTRAINED
                end

                % Read constrained targets
                wantedovershoot = app.WantedOvershootSpinner.Value;
                wantedrisetime  = app.WantedRiseTimesSpinner.Value;
                wantedess       = app.WantedSteadyStateErrorSpinner.Value;

                % Read selected system data
                [model, initFcn, costFcnRaw, params] = app.getSelectedSystemData();
                app.CurrentModel = string(model);

                % Main folder
                mainFolder = fileparts(mfilename('fullpath'));

                % Force current folder to app folder
                cd(mainFolder);

                % Clean old Simulink generated artifacts first
                app.cleanupSimulinkArtifacts(mainFolder);

                % Add Initialization folder
                initFolder = fullfile(mainFolder, 'Initialization');
                if ~isfolder(initFolder)
                    error('Initialization folder not found: %s', initFolder);
                end
                addpath(genpath(initFolder));

                % Add Costs Functions folder
                costFolder = fullfile(mainFolder, 'Costs Functions');
                if ~isfolder(costFolder)
                    error('Costs Functions folder not found: %s', costFolder);
                end
                addpath(genpath(costFolder));

                % Add Tuning Methods folder
                tuningFolder = fullfile(mainFolder, 'Tuning Methods');
                if ~isfolder(tuningFolder)
                    error('Tuning Methods folder not found: %s', tuningFolder);
                end
                addpath(genpath(tuningFolder));

                % Add model folder
                modelFolder = fullfile(mainFolder, 'Simulink Models', controllerType);
                if ~isfolder(modelFolder)
                    error('Model folder not found: %s', modelFolder);
                end
                addpath(genpath(modelFolder));

                app.throwIfStopped();

                % Send parameters to base workspace
                app.assignSystemParamsToBase(params);

                % Run initialization function
                initFcn();

                % Assign simulation settings
                assignin('base', 'sim_time', simTime);
                assignin('base', 'optimization_mode', optimization_mode);
                assignin('base', 'wantedovershoot', wantedovershoot);
                assignin('base', 'wantedrisetime', wantedrisetime);
                assignin('base', 'wantedess', wantedess);

                if ~evalin('base', 'exist(''ref'', ''var'')')
                    assignin('base', 'ref', 1);
                end

                app.throwIfStopped();

                % Close old models
                bdclose('all');

                % Build model path
                modelPath = fullfile(modelFolder, model + ".slx");
                if ~isfile(modelPath)
                    error('Model file not found: %s', modelPath);
                end

                % Create clean session-specific Simulink cache/codegen folders
                sessionRoot = fullfile(tempdir, 'BHA_App_Simulink_Cache');
                if ~exist(sessionRoot, 'dir')
                    mkdir(sessionRoot);
                end

                cacheFolder = fullfile(sessionRoot, 'slcache');
                codegenFolder = fullfile(sessionRoot, 'codegen');

                if exist(cacheFolder, 'dir')
                    try
                        rmdir(cacheFolder, 's');
                    catch
                    end
                end

                if exist(codegenFolder, 'dir')
                    try
                        rmdir(codegenFolder, 's');
                    catch
                    end
                end

                mkdir(cacheFolder);
                mkdir(codegenFolder);

                Simulink.fileGenControl('set', ...
                    'CacheFolder', cacheFolder, ...
                    'CodeGenFolder', codegenFolder, ...
                    'createDir', true);

                % Load selected model
                load_system(modelPath);

                % Speed-up settings
                set_param(model, 'SimulationMode', 'accelerator');
                set_param(model, 'FastRestart', 'on');

                drawnow;
                app.throwIfStopped();

                % Build wrapped cost function
                costFcn = @(Kopt) app.controllerCostWrapper(Kopt, controllerType, costFcnRaw);
                stopFcn = @() app.isStopRequested();

                % Live progress callback
                progressFcn = @(history) app.updateLiveOptimizationDisplay(history);

                % Run BHO only
                history = struct( ...
                    'Iteration', [], ...
                    'Kp', [], ...
                    'Ki', [], ...
                    'Kd', [], ...
                    'Cost', [], ...
                    'Overshoot', [], ...
                    'RiseTime', [], ...
                    'ESS', []);

                [bestKopt_BHO, history] = BlackHoleAlgorithm( ...
                    costFcn, nPop, MaxIt, VarMin, VarMax, ...
                    controllerType, model, stopFcn, progressFcn);

                % Guard against stop / empty return
                if app.StopRequested || isempty(bestKopt_BHO)
                    error('BHA_App:StoppedByUser', 'Process stopped by user.');
                end

                bestK = app.expandControllerGains(bestKopt_BHO, controllerType);

                assignin('base', 'kp', bestK(1));
                assignin('base', 'ki', bestK(2));
                assignin('base', 'kd', bestK(3));

                app.throwIfStopped();

                simOut = sim(model, 'StopTime', num2str(simTime), 'CaptureErrors', 'on');

                if ~isempty(simOut.ErrorMessage)
                    error('Simulation error in BHO method: %s', simOut.ErrorMessage);
                end

                resp = simOut.OutputResponse;
                t = resp.Time;
                y = resp.Data;
                ref = evalin('base', 'step_amp');
                u = ref * ones(size(t));

                try
                    info = stepinfo(y, t, ref, 'SettlingTimeThreshold', 0.02);
                catch
                    info = stepinfo(y, t);
                end

                results = struct();
                results.BHO.t        = t;
                results.BHO.y        = y;
                results.BHO.u        = u;
                results.BHO.Gains    = bestK;
                results.BHO.stepinfo = info;
                results.BHO.ess      = abs(ref - y(end));

                % Turn Fast Restart off after finishing
                try
                    if strlength(app.CurrentModel) > 0 && bdIsLoaded(char(app.CurrentModel))
                        set_param(char(app.CurrentModel), 'FastRestart', 'off');
                    end
                catch
                end

                % Plot input/output comparison
                cla(app.System_Response);
                hold(app.System_Response, 'on');
                grid(app.System_Response, 'on');

                stairs(app.System_Response, results.BHO.t, results.BHO.u, ...
                    'r--', 'LineWidth', 2, 'DisplayName', 'Reference');

                plot(app.System_Response, results.BHO.t, results.BHO.y, ...
                    'b-', 'LineWidth', 2, 'DisplayName', 'Output');

                legend(app.System_Response, 'show', 'Location', 'best');
                title(app.System_Response, [char(systemName) ' - ' controllerType ' Input vs Output']);
                xlabel(app.System_Response, 'Time (s)');
                ylabel(app.System_Response, 'Amplitude');

                hold(app.System_Response, 'off');

                app.throwIfStopped();

                % Final refresh for table and cost plot
                app.updateLiveOptimizationDisplay(history);

                app.throwIfStopped();

                % Show final status image
                app.showStatusImage(3);

                % Update final status
                app.Status.FontColor = [0 0 0];
                app.Status.Text = 'Optimization completed successfully.';

                % Mark run as completed and enable saving
                app.LastRunCompleted = true;
                app.LastSystemName = string(systemName);
                app.LastControllerType = string(controllerType);
                app.SaveResultsButton.Enable = 'on';

                drawnow;

            catch ME
                try
                    if strlength(app.CurrentModel) > 0 && bdIsLoaded(char(app.CurrentModel))
                        set_param(char(app.CurrentModel), 'FastRestart', 'off');
                    end
                catch
                end

                app.stopActiveSimulation();
                app.hideAllStatusImages();

                app.LastRunCompleted = false;
                app.SaveResultsButton.Enable = 'off';

                if strcmp(ME.identifier, 'BHA_App:StoppedByUser')
                    app.Status.FontColor = [0 0 0];
                    app.Status.Text = 'Stopped by user.';
                    drawnow;
                    return;
                end

                app.Status.FontColor = [1 0 0];
                app.Status.Text = ['Error: ' ME.message];
                drawnow;

                uialert(app.UIFigure, ME.message, 'Run Error');
            end
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            % Request stop for algorithm and simulations
            app.StopRequested = true;

            % Try to stop any active Simulink execution
            app.stopActiveSimulation();

            % Reset save state
            app.LastRunCompleted = false;
            app.SaveResultsButton.Enable = 'off';

            % Reset visible status immediately
            app.hideAllStatusImages();
            app.Status.Text = 'Ready';
            app.UIFigure.Pointer = 'arrow';
            drawnow;
        end

        % Value changed function: BestOptimizationModeCheckBox
        function BestOptimizationModeCheckBoxValueChanged(app, event)
            app.updateOptimizationModeUI();

        end

        % Button pushed function: ResultsPanelButton
        function ResultsPanelButtonPushed(app, event)
            app.ResultsPanel.Visible = 'on';
            drawnow;
        end

        % Button pushed function: ExitResultsPanelButton
        function ExitResultsPanelButtonPushed(app, event)
            app.ResultsPanel.Visible = 'off';
            drawnow;
        end

        % Value changed function: NumeratorCoefficientsEditField
        function NumeratorCoefficientsEditFieldValueChanged(app, event)
            app.updateCustomTFPreview();
        end

        % Value changed function: DenominatorCoefficientsEditField
        function DenominatorCoefficientsEditFieldValueChanged(app, event)
            app.updateCustomTFPreview();
        end

        % Button pushed function: SaveResultsButton
        function SaveResultsButtonPushed(app, event)
            if ~app.LastRunCompleted
                uialert(app.UIFigure, ...
                    'No completed simulation is available to save.', ...
                    'Save Error', ...
                    'Icon', 'error');
                return;
            end

            try
                mainFolder = fileparts(mfilename('fullpath'));
                resultsRoot = fullfile(mainFolder, 'Results');

                systemFolderName = app.getSystemFolderName(app.LastSystemName);
                controllerFolderName = char(app.LastControllerType);

                saveFolder = fullfile(resultsRoot, systemFolderName, controllerFolderName);

                if ~exist(saveFolder, 'dir')
                    mkdir(saveFolder);
                end

                testNumber = app.getNextTestNumber(saveFolder, systemFolderName, controllerFolderName);
                baseFileName = sprintf('%s_%s_test%d', systemFolderName, controllerFolderName, testNumber);

                costImagePath = fullfile(saveFolder, [baseFileName '_Cost.png']);
                responseImagePath = fullfile(saveFolder, [baseFileName '_Response.png']);
                tableFilePath = fullfile(saveFolder, [baseFileName '_Table.xlsx']);

                % Save plots
                app.saveAxesAsPNG(app.Cost, costImagePath);
                app.saveAxesAsPNG(app.System_Response, responseImagePath);

                % Save table
                app.saveResultsTableToExcel(tableFilePath);

                app.LastResultsFolder = string(saveFolder);

                uialert(app.UIFigure, ...
                    sprintf('Results were saved successfully.\nLocation:\n%s', saveFolder), ...
                    'Save Complete', ...
                    'Icon', 'success');

            catch ME
                uialert(app.UIFigure, ...
                    ME.message, ...
                    'Save Error', ...
                    'Icon', 'error');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.BlackHoleOptimizerLabel = uilabel(app.UIFigure);
            app.BlackHoleOptimizerLabel.Text = '   Black Hole Optimizer';
            app.SystemSelectionPanel = uipanel(app.UIFigure);
            app.SystemSelectionPanel.Title = 'System Selection';
            app.SelectSystemDropDownLabel = uilabel(app.SystemSelectionPanel);
            app.SelectSystemDropDownLabel.Text = 'Select System';
            app.SelectSystemDropDown = uidropdown(app.SystemSelectionPanel);
            app.SelectSystemDropDown.Items = {'', 'Ball & Beam', 'Cruise Control', 'Motor Speed Control', 'Custom TF'};
            app.SelectSystemDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectSystemDropDownValueChanged, true);
            app.SelectSystemDropDown.Value = '';
            app.TypeofControllerDropDownLabel = uilabel(app.SystemSelectionPanel);
            app.TypeofControllerDropDownLabel.Text = 'Type of Controller';
            app.TypeofControllerDropDown = uidropdown(app.SystemSelectionPanel);
            app.TypeofControllerDropDown.Items = {'PI', 'PD', 'PID'};
            app.TypeofControllerDropDown.Value = 'PI';
            app.OptimizationSettingsPanel = uipanel(app.UIFigure);
            app.OptimizationSettingsPanel.Title = 'Optimization Settings';
            app.PopulationNumberofStarsLabel = uilabel(app.OptimizationSettingsPanel);
            app.PopulationNumberofStarsLabel.Text = 'Population (Number of Stars)';
            app.PopulationNumberofStarsSpinner = uispinner(app.OptimizationSettingsPanel);
            app.PopulationNumberofStarsSpinner.Step = 5;
            app.PopulationNumberofStarsSpinner.Limits = [0 Inf];
            app.MaxIterationsSpinnerLabel = uilabel(app.OptimizationSettingsPanel);
            app.MaxIterationsSpinnerLabel.Text = 'Max Iterations';
            app.MaxIterationsSpinner = uispinner(app.OptimizationSettingsPanel);
            app.MaxIterationsSpinner.Step = 5;
            app.MaxIterationsSpinner.Limits = [0 Inf];
            app.MaxKpSpinnerLabel = uilabel(app.OptimizationSettingsPanel);
            app.MaxKpSpinnerLabel.Text = 'Max Kp';
            app.MaxKpSpinner = uispinner(app.OptimizationSettingsPanel);
            app.MaxKpSpinner.Step = 100;
            app.MaxKpSpinner.Limits = [0 Inf];
            app.MaxKpSpinner.Value = 500;
            app.MaxKiSpinnerLabel = uilabel(app.OptimizationSettingsPanel);
            app.MaxKiSpinnerLabel.Text = 'Max Ki';
            app.MaxKiSpinner = uispinner(app.OptimizationSettingsPanel);
            app.MaxKiSpinner.Step = 100;
            app.MaxKiSpinner.Limits = [0 Inf];
            app.MaxKiSpinner.Value = 500;
            app.MaxKdSpinnerLabel = uilabel(app.OptimizationSettingsPanel);
            app.MaxKdSpinnerLabel.Text = 'Max Kd';
            app.MaxKdSpinner = uispinner(app.OptimizationSettingsPanel);
            app.MaxKdSpinner.Step = 100;
            app.MaxKdSpinner.Limits = [0 Inf];
            app.MaxKdSpinner.Value = 500;
            app.RunButton = uibutton(app.OptimizationSettingsPanel, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.SimulationTimeSpinnerLabel = uilabel(app.OptimizationSettingsPanel);
            app.SimulationTimeSpinnerLabel.Text = 'Simulation Time';
            app.SimulationTimeSpinner = uispinner(app.OptimizationSettingsPanel);
            app.SimulationTimeSpinner.Step = 5;
            app.SimulationTimeSpinner.Limits = [0 Inf];
            app.SimulationTimeSpinner.Value = 10;
            app.StopButton = uibutton(app.OptimizationSettingsPanel, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Enable = 'off';
            app.StopButton.Text = 'Stop';
            app.BestOptimizationModeCheckBox = uicheckbox(app.OptimizationSettingsPanel);
            app.BestOptimizationModeCheckBox.ValueChangedFcn = createCallbackFcn(app, @BestOptimizationModeCheckBoxValueChanged, true);
            app.BestOptimizationModeCheckBox.Text = 'Best Optimization Mode';
            app.BestOptimizationModeCheckBox.Value = true;
            app.WantedOvershootLabel = uilabel(app.OptimizationSettingsPanel);
            app.WantedOvershootLabel.Enable = 'off';
            app.WantedOvershootLabel.Text = 'Wanted Overshoot (%)';
            app.WantedOvershootSpinner = uispinner(app.OptimizationSettingsPanel);
            app.WantedOvershootSpinner.Limits = [0 100];
            app.WantedOvershootSpinner.Enable = 'off';
            app.WantedRiseTimesSpinnerLabel = uilabel(app.OptimizationSettingsPanel);
            app.WantedRiseTimesSpinnerLabel.Enable = 'off';
            app.WantedRiseTimesSpinnerLabel.Text = 'Wanted Rise Time (s)';
            app.WantedRiseTimesSpinner = uispinner(app.OptimizationSettingsPanel);
            app.WantedRiseTimesSpinner.Limits = [0 Inf];
            app.WantedRiseTimesSpinner.Enable = 'off';
            app.WantedSteadyStateErrorLabel = uilabel(app.OptimizationSettingsPanel);
            app.WantedSteadyStateErrorLabel.Enable = 'off';
            app.WantedSteadyStateErrorLabel.Text = 'Wanted Steady-State Error (%)';
            app.WantedSteadyStateErrorSpinner = uispinner(app.OptimizationSettingsPanel);
            app.WantedSteadyStateErrorSpinner.Limits = [0 100];
            app.WantedSteadyStateErrorSpinner.Enable = 'off';
            app.MinKdSpinnerLabel = uilabel(app.OptimizationSettingsPanel);
            app.MinKdSpinnerLabel.Text = 'Min Kd';
            app.MinKdSpinner = uispinner(app.OptimizationSettingsPanel);
            app.MinKdSpinner.Step = 100;
            app.MinKdSpinner.Limits = [0 Inf];
            app.MinKiSpinnerLabel = uilabel(app.OptimizationSettingsPanel);
            app.MinKiSpinnerLabel.Text = 'Min Ki';
            app.MinKiSpinner = uispinner(app.OptimizationSettingsPanel);
            app.MinKiSpinner.Step = 100;
            app.MinKiSpinner.Limits = [0 Inf];
            app.MinKpSpinnerLabel = uilabel(app.OptimizationSettingsPanel);
            app.MinKpSpinnerLabel.Text = 'Min Kp';
            app.MinKpSpinner = uispinner(app.OptimizationSettingsPanel);
            app.MinKpSpinner.Step = 100;
            app.MinKpSpinner.Limits = [0 Inf];
            app.ParametersPanel = uipanel(app.UIFigure);
            app.ParametersPanel.Title = 'Parameters';
            app.CruiseParametersPanel = uipanel(app.ParametersPanel);
            app.CruiseParametersPanel.Visible = 'off';
            app.mSpinner_2Label = uilabel(app.CruiseParametersPanel);
            app.mSpinner_2Label.Text = 'm';
            app.m_cruiseSpinner = uispinner(app.CruiseParametersPanel);
            app.bSpinnerLabel = uilabel(app.CruiseParametersPanel);
            app.bSpinnerLabel.Text = 'b';
            app.b_cruiseSpinner = uispinner(app.CruiseParametersPanel);
            app.SpeedSpinnerLabel = uilabel(app.CruiseParametersPanel);
            app.SpeedSpinnerLabel.Text = 'Speed';
            app.Speed_cruiseSpinner = uispinner(app.CruiseParametersPanel);
            app.Speed_cruiseSpinner.Limits = [0.0001 Inf];
            app.Speed_cruiseSpinner.Value = 1;
            app.MotorParametersPanel = uipanel(app.ParametersPanel);
            app.MotorParametersPanel.Visible = 'off';
            app.RSpinner_2Label = uilabel(app.MotorParametersPanel);
            app.RSpinner_2Label.Text = 'R';
            app.R_motorSpinner = uispinner(app.MotorParametersPanel);
            app.R_motorSpinner.Limits = [0 Inf];
            app.LSpinner_2Label = uilabel(app.MotorParametersPanel);
            app.LSpinner_2Label.Text = 'L';
            app.L_motorSpinner = uispinner(app.MotorParametersPanel);
            app.L_motorSpinner.Limits = [0 Inf];
            app.mSpinner_2Label_2 = uilabel(app.MotorParametersPanel);
            app.mSpinner_2Label_2.Text = 'J';
            app.J_motorSpinner = uispinner(app.MotorParametersPanel);
            app.J_motorSpinner.Limits = [0 Inf];
            app.bSpinner_2Label = uilabel(app.MotorParametersPanel);
            app.bSpinner_2Label.Text = 'b';
            app.b_motorSpinner = uispinner(app.MotorParametersPanel);
            app.b_motorSpinner.Limits = [0 Inf];
            app.KSpinnerLabel = uilabel(app.MotorParametersPanel);
            app.KSpinnerLabel.Text = 'K';
            app.K_motorSpinner = uispinner(app.MotorParametersPanel);
            app.K_motorSpinner.Limits = [0 Inf];
            app.RPMSpinnerLabel = uilabel(app.MotorParametersPanel);
            app.RPMSpinnerLabel.Text = 'RPM';
            app.RPM_motorSpinner = uispinner(app.MotorParametersPanel);
            app.BandBParametersPanel = uipanel(app.ParametersPanel);
            app.BandBParametersPanel.Visible = 'off';
            app.mSpinnerLabel = uilabel(app.BandBParametersPanel);
            app.mSpinnerLabel.Text = 'm';
            app.m_b_and_bSpinner = uispinner(app.BandBParametersPanel);
            app.gSpinnerLabel = uilabel(app.BandBParametersPanel);
            app.gSpinnerLabel.Text = 'g';
            app.g_b_and_bSpinner = uispinner(app.BandBParametersPanel);
            app.g_b_and_bSpinner.Value = 9.8;
            app.dSpinnerLabel = uilabel(app.BandBParametersPanel);
            app.dSpinnerLabel.Text = 'd';
            app.d_b_and_bSpinner = uispinner(app.BandBParametersPanel);
            app.RSpinnerLabel = uilabel(app.BandBParametersPanel);
            app.RSpinnerLabel.Text = 'R';
            app.R_b_and_bSpinner = uispinner(app.BandBParametersPanel);
            app.LSpinnerLabel = uilabel(app.BandBParametersPanel);
            app.LSpinnerLabel.Text = 'L';
            app.L_b_and_bSpinner = uispinner(app.BandBParametersPanel);
            app.StepAmplitudeLabel = uilabel(app.BandBParametersPanel);
            app.StepAmplitudeLabel.Text = {'Step'; 'Amplitude'};
            app.StepAmplitude_b_and_bSpinner = uispinner(app.BandBParametersPanel);
            app.StepAmplitude_b_and_bSpinner.Limits = [0.0001 Inf];
            app.StepAmplitude_b_and_bSpinner.Value = 1;
            app.JSpinnerLabel = uilabel(app.BandBParametersPanel);
            app.JSpinnerLabel.Text = 'J';
            app.J_b_and_bSpinner = uispinner(app.BandBParametersPanel);
            app.CustomTFPanel = uipanel(app.ParametersPanel);
            app.CustomTFPanel.Visible = 'off';
            app.CustomTFAxes = uiaxes(app.CustomTFPanel);
            app.NumeratorCoefficientsEditFieldLabel = uilabel(app.CustomTFPanel);
            app.NumeratorCoefficientsEditFieldLabel.Text = 'Numerator Coefficients';
            app.NumeratorCoefficientsEditField = uieditfield(app.CustomTFPanel, 'text');
            app.NumeratorCoefficientsEditField.ValueChangedFcn = createCallbackFcn(app, @NumeratorCoefficientsEditFieldValueChanged, true);
            app.DenominatorCoefficientsEditFieldLabel = uilabel(app.CustomTFPanel);
            app.DenominatorCoefficientsEditFieldLabel.Text = 'Denominator Coefficients';
            app.DenominatorCoefficientsEditField = uieditfield(app.CustomTFPanel, 'text');
            app.DenominatorCoefficientsEditField.ValueChangedFcn = createCallbackFcn(app, @DenominatorCoefficientsEditFieldValueChanged, true);
            app.StepAmplitudeLabel_2 = uilabel(app.CustomTFPanel);
            app.StepAmplitudeLabel_2.Text = 'Step Amplitude';
            app.StepAmplitudeCustomSpinner = uispinner(app.CustomTFPanel);
            app.StatusPanel = uipanel(app.UIFigure);
            app.StatusPanel.Title = 'Status';
            app.Image1 = uiimage(app.StatusPanel);
            app.Image1.Visible = 'off';
            app.Image1.ImageSource = fullfile(pathToMLAPP, 'images', 'First.gif');
            app.Status = uilabel(app.StatusPanel);
            app.Status.Text = 'Status';
            app.Image2 = uiimage(app.StatusPanel);
            app.Image2.Visible = 'off';
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'images', 'Second.gif');
            app.Image3 = uiimage(app.StatusPanel);
            app.Image3.Visible = 'off';
            app.Image3.ImageSource = fullfile(pathToMLAPP, 'images', '5.1.2 finish case 1.png');
            app.ResultsPanel = uipanel(app.UIFigure);
            app.ResultsPanel.Title = 'Results';
            app.ResultsPanel.Visible = 'off';
            app.Cost = uiaxes(app.ResultsPanel);
            title(app.Cost, 'Cost')
            xlabel(app.Cost, 'X')
            ylabel(app.Cost, 'Y')
            zlabel(app.Cost, 'Z')
            app.System_Response = uiaxes(app.ResultsPanel);
            title(app.System_Response, 'System')
            xlabel(app.System_Response, 'X')
            ylabel(app.System_Response, 'Y')
            zlabel(app.System_Response, 'Z')
            app.ResultsTable = uitable(app.ResultsPanel);
            app.ResultsTable.ColumnName = {'Iteration'; 'Kp'; 'Ki'; 'Kd'; 'Cost'; 'Overshoot'; 'Rise Time'; 'Steady-State Error'};
            app.ResultsTable.ColumnRearrangeable = 'on';
            app.ResultsTable.RowName = {};
            app.ExitResultsPanelButton = uibutton(app.UIFigure, 'push');
            app.ExitResultsPanelButton.ButtonPushedFcn = createCallbackFcn(app, @ExitResultsPanelButtonPushed, true);
            app.ExitResultsPanelButton.Text = 'Exit Results Panel';
            app.ResultsPanelButton = uibutton(app.UIFigure, 'push');
            app.ResultsPanelButton.ButtonPushedFcn = createCallbackFcn(app, @ResultsPanelButtonPushed, true);
            app.ResultsPanelButton.Text = 'Results Panel';
            app.SaveResultsButton = uibutton(app.UIFigure, 'push');
            app.SaveResultsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveResultsButtonPushed, true);
            app.SaveResultsButton.Text = 'Save Results';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BHA_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end