classdef test < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        Image523          matlab.ui.control.Image
        Image522          matlab.ui.control.Image
        Image521          matlab.ui.control.Image
        Image512          matlab.ui.control.Image
        Image511          matlab.ui.control.Image
        Image4            matlab.ui.control.Image
        Image3            matlab.ui.control.Image
        Image2            matlab.ui.control.Image
        Image1            matlab.ui.control.Image
        BHADropDown       matlab.ui.control.DropDown
        BHADropDownLabel  matlab.ui.control.Label
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Image1.Visible = 'off';
        end

        function resetAllGIFs(app)
            pathToMLAPP = fileparts(mfilename('fullpath'));
            blankFile = fullfile(pathToMLAPP, 'images', 'blank.png');

            % Hide all
            app.Image1.Visible   = 'off';
            app.Image2.Visible   = 'off';
            app.Image3.Visible   = 'off';
            app.Image4.Visible   = 'off';
            app.Image511.Visible = 'off';
            app.Image512.Visible = 'off';
            app.Image521.Visible = 'off';
            app.Image522.Visible = 'off';
            app.Image523.Visible = 'off';

            % Reset GIFs through blank image
            app.Image1.ImageSource   = blankFile;
            app.Image3.ImageSource   = blankFile;
            app.Image4.ImageSource   = blankFile;
            app.Image511.ImageSource = blankFile;
            app.Image522.ImageSource = blankFile;

            drawnow;
            pause(0.05);

            app.Image1.ImageSource   = fullfile(pathToMLAPP, 'images', '1 stars generation.gif');
            app.Image2.ImageSource   = fullfile(pathToMLAPP, 'images', '2 BH determined.png');
            app.Image3.ImageSource   = fullfile(pathToMLAPP, 'images', '3 star sucking.gif');
            app.Image4.ImageSource   = fullfile(pathToMLAPP, 'images', '4 star generation again with the prebious BH.gif');
            app.Image511.ImageSource = fullfile(pathToMLAPP, 'images', '5.1.1 star sucking if the same BH.gif');
            app.Image512.ImageSource = fullfile(pathToMLAPP, 'images', '5.1.2 finish case 1.png');
            app.Image521.ImageSource = fullfile(pathToMLAPP, 'images', '5.2.1 new BH determine.png');
            app.Image522.ImageSource = fullfile(pathToMLAPP, 'images', '5.2.2 star sucking if new BH.gif');
            app.Image523.ImageSource = fullfile(pathToMLAPP, 'images', '5.2.3 finish case 2.png');
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: BHADropDown
        function BHADropDownValueChanged(app, event)
            value = app.BHADropDown.Value;

            % Hide everything + restart all GIFs
            resetAllGIFs(app);

            switch value
                case 'None'
                    % nothing

                case '1 stars generation'
                    app.Image1.Visible = 'on';

                case '2 BH determined'
                    app.Image2.Visible = 'on';

                case '3 star sucking'
                    app.Image3.Visible = 'on';

                case '4 star generation again with the prebious BH'
                    app.Image4.Visible = 'on';

                case '5.1.1 star sucking if the same BH'
                    app.Image511.Visible = 'on';

                case '5.1.2 finish case 1'
                    app.Image512.Visible = 'on';

                case '5.2.1 new BH determine'
                    app.Image521.Visible = 'on';

                case '5.2.2 star sucking if new BH'
                    app.Image522.Visible = 'on';

                case '5.2.3 finish case 2'
                    app.Image523.Visible = 'on';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.BHADropDownLabel = uilabel(app.UIFigure);
            app.BHADropDownLabel.Text = 'BHA';
            app.BHADropDown = uidropdown(app.UIFigure);
            app.BHADropDown.Items = {'None', '1 stars generation', '2 BH determined', '3 star sucking', '4 star generation again with the prebious BH', '5.1.1 star sucking if the same BH', '5.1.2 finish case 1', '5.2.1 new BH determine', '5.2.2 star sucking if new BH', '5.2.3 finish case 2'};
            app.BHADropDown.ValueChangedFcn = createCallbackFcn(app, @BHADropDownValueChanged, true);
            app.BHADropDown.Value = 'None';
            app.Image1 = uiimage(app.UIFigure);
            app.Image1.Visible = 'off';
            app.Image1.ImageSource = fullfile(pathToMLAPP, 'images', '1 stars generation.gif');
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.Visible = 'off';
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'images', '2 BH determined.png');
            app.Image3 = uiimage(app.UIFigure);
            app.Image3.Visible = 'off';
            app.Image3.ImageSource = fullfile(pathToMLAPP, 'images', '3 star sucking.gif');
            app.Image4 = uiimage(app.UIFigure);
            app.Image4.Visible = 'off';
            app.Image4.ImageSource = fullfile(pathToMLAPP, 'images', '4 star generation again with the prebious BH.gif');
            app.Image511 = uiimage(app.UIFigure);
            app.Image511.Visible = 'off';
            app.Image511.ImageSource = fullfile(pathToMLAPP, 'images', '5.1.1 star sucking if the same BH.gif');
            app.Image512 = uiimage(app.UIFigure);
            app.Image512.Visible = 'off';
            app.Image512.ImageSource = fullfile(pathToMLAPP, 'images', '5.1.2 finish case 1.png');
            app.Image521 = uiimage(app.UIFigure);
            app.Image521.Visible = 'off';
            app.Image521.ImageSource = fullfile(pathToMLAPP, 'images', '5.2.1 new BH determine.png');
            app.Image522 = uiimage(app.UIFigure);
            app.Image522.Visible = 'off';
            app.Image522.ImageSource = fullfile(pathToMLAPP, 'images', '5.2.2 star sucking if new BH.gif');
            app.Image523 = uiimage(app.UIFigure);
            app.Image523.Visible = 'off';
            app.Image523.ImageSource = fullfile(pathToMLAPP, 'images', '5.2.3 finish case 2.png');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = test

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end