classdef PID_dc_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        TabGroup                     matlab.ui.container.TabGroup
        ConnectionTab                matlab.ui.container.Tab
        ConfigrationPanel            matlab.ui.container.Panel
        BoardsDropDown               matlab.ui.control.DropDown
        BoardLabel                   matlab.ui.control.Label
        AvailablePortsDropDown       matlab.ui.control.DropDown
        AvailablePortsDropDownLabel  matlab.ui.control.Label
        SelectYourBoardLabel         matlab.ui.control.Label
        SelectYourPortLabel          matlab.ui.control.Label
        TestingPanel                 matlab.ui.container.Panel
        TestBlinkD13Button           matlab.ui.control.Button
        RefreshButton                matlab.ui.control.Button
        ConnectionPanel              matlab.ui.container.Panel
        DisconnectButton             matlab.ui.control.Button
        ConnectButton                matlab.ui.control.Button
        StatusPanel                  matlab.ui.container.Panel
        StatusLamp                   matlab.ui.control.Lamp
        StatusLampLabel              matlab.ui.control.Label
        StatusNotconnectedLabel      matlab.ui.control.Label
        Tab                          matlab.ui.container.Tab
        MotorControlTab              matlab.ui.container.Tab
        DirectionDropDown            matlab.ui.control.DropDown
        DirectionDropDownLabel       matlab.ui.control.Label
        RPM_TargetKnob               matlab.ui.control.Knob
        RPM_TargetKnobLabel          matlab.ui.control.Label
        StopButton                   matlab.ui.control.Button
        RunButton                    matlab.ui.control.Button
    end


    properties (Access = private)
        hwConnected (1,1) logical = false
        lastCount   (1,1) double  = 0
        % ===== Hardware =====
        a
        enc

        % ===== Pins / Settings =====
        COM   = '/dev/ttyUSB0';
        BOARD = 'Nano3';

        ENA   = 'D9';
        IN1   = 'D8';
        IN2   = 'D7';
        ENC_A = 'D2';
        ENC_B = 'D3';


        CPR_MOTOR_4X  = 64.0
        GEAR_RATIO    = 131.25
        RPM_AT_OUTPUT = true
        CPR_TOTAL

        SAMPLE_MS = 50
        TsTarget

        PWM_MIN = 0
        PWM_MAX = 255

        % ===== Control variables (linked to UI) =====
        SETPOINT_RPM = 50.0
        forwardDir   = true   % true=Forward, false=Reverse

        % ===== PID gains =====
        Kp = 5.0
        Ki = 3.0
        Kd = 0.03

        rpmAlpha = 0.25
        dAlpha   = 0.15
        I_LIMIT  = 200.0

        SOFTSTART_SEC = 0.4
        SOFTSTART_PWM = 50

        % ===== Runtime states =====
        tmr
        Running = false
        stopFlag = false

        t0
        tPrev
        firstSample = true

        integral  = 0.0
        prevError = 0.0
        dFiltered = 0.0
        rpmFilt   = 0.0
    end

    methods (Access = private)


        function controlStep(app)
            if ~app.hwConnected || isempty(app.a) || isempty(app.enc)
                return; % keep app running, just skip control
            end

            if ~app.Running || app.stopFlag
                return;
            end

            t = toc(app.t0);

            dt = t - app.tPrev;
            app.tPrev = t;
            if dt <= 0
                return;
            end

            % Encoder delta
            count = readCount(app.enc);
            delta = count - app.lastCount;
            app.lastCount = count;

            rpmRaw = (double(delta) * 60.0) / (double(app.CPR_TOTAL) * dt);
            rpmRawMag = abs(rpmRaw);

            % EMA filter
            app.rpmFilt = app.rpmAlpha*rpmRawMag + (1-app.rpmAlpha)*app.rpmFilt;

            % init to avoid derivative kick
            if app.firstSample
                app.rpmFilt = rpmRawMag;
                app.prevError = app.SETPOINT_RPM - app.rpmFilt;
                app.dFiltered = 0;
                app.integral = 0;
                app.firstSample = false;
            end

            % Soft-start + PID
            if t < app.SOFTSTART_SEC
                pwmCmd = app.SOFTSTART_PWM;
            else
                if app.SETPOINT_RPM < 0.5
                    app.integral = 0;
                    app.prevError = 0;
                    app.dFiltered = 0;
                    pwmCmd = 0;
                else
                    err = app.SETPOINT_RPM - app.rpmFilt;

                    app.integral = app.integral + err*dt;
                    app.integral = max(-app.I_LIMIT, min(app.I_LIMIT, app.integral));

                    d = (err - app.prevError)/dt;
                    app.dFiltered = app.dAlpha*d + (1-app.dAlpha)*app.dFiltered;

                    u = app.Kp*err + app.Ki*app.integral + app.Kd*app.dFiltered;
                    pwmCmd = round(u);

                    % Saturation + anti-windup
                    if pwmCmd > app.PWM_MAX
                        pwmCmd = app.PWM_MAX;
                        if err > 0
                            app.integral = app.integral - err*dt;
                        end
                    elseif pwmCmd < app.PWM_MIN
                        pwmCmd = app.PWM_MIN;
                        if err < 0
                            app.integral = app.integral - err*dt;
                        end
                    end

                    app.prevError = err;
                end
            end

            app.applyMotorPWM(pwmCmd);
        end

        function stopNow(app)
            app.stopFlag = true;

            if ~isempty(app.tmr) && isvalid(app.tmr)
                try
                    stop(app.tmr);
                catch
                end
            end

            % Motor OFF
            try
                app.applyMotorPWM(0);
            catch
            end

            app.Running = false;

            % UI enable/disable
            if ~isempty(app.RunButton) && isvalid(app.RunButton)
                app.RunButton.Enable = "on";
            end
            if ~isempty(app.StopButton) && isvalid(app.StopButton)
                app.StopButton.Enable = "off";
            end
        end

        function applyMotorPWM(app, pwm)
            if ~app.hwConnected || isempty(app.a)
                return;
            end

            pwm = max(app.PWM_MIN, min(app.PWM_MAX, pwm));

            if pwm == 0
                writeDigitalPin(app.a, app.IN1, 0);
                writeDigitalPin(app.a, app.IN2, 0);
                writePWMDutyCycle(app.a, app.ENA, 0);
                return;
            end

            if app.forwardDir
                writeDigitalPin(app.a, app.IN1, 1);
                writeDigitalPin(app.a, app.IN2, 0);
            else
                writeDigitalPin(app.a, app.IN1, 0);
                writeDigitalPin(app.a, app.IN2, 1);
            end

            writePWMDutyCycle(app.a, app.ENA, pwm/255);
        end

        function initConnectionTab(app)
            % Boards
            app.BoardsDropDown.Items = {'Uno','Nano3','Mega2560','Leonardo','Due'};
            if isempty(app.BoardsDropDown.Value)
                app.BoardsDropDown.Value = 'Nano3';
            end

            % Ports
            app.refreshPorts();

            % Buttons initial state
            app.ConnectButton.Enable        = "on";
            app.DisconnectButton.Enable     = "off";
            app.TestBlinkD13Button.Enable   = "off";

            % Motor controls disabled until connected
            app.RunButton.Enable  = "off";
            app.StopButton.Enable = "off";

            app.setStatus(false,"Not connected");
        end

        function refreshPorts(app)
            % Store current selection before modifying Items
            current = string(app.AvailablePortsDropDown.Value);

            % Show scanning state (yellow)
            app.setStatus("warn","Scanning ports...");
            drawnow;

            % Small scan window to reduce temporary OS glitches
            scanSec = 0.6;  % adjust if you want (0.3–1.0 is fine)
            tStart  = tic;

            portsAll = strings(1,0);
            while toc(tStart) < scanSec
                portsNow = serialportlist("available");
                portsAll = unique([portsAll, portsNow(:)']); %ok
                pause(0.1);
                drawnow;
            end

            ports = portsAll;

            % Prefer Arduino-like ports
            isCandidate = contains(ports,"/dev/ttyACM") | ...
                contains(ports,"/dev/ttyUSB") | ...
                startsWith(ports,"COM");
            if any(isCandidate)
                ports = ports(isCandidate);
            end

            % Force row format (1xN)
            ports = ports(:)';

            % Case: No ports detected
            if isempty(ports)

                % If already connected, keep current port visible
                if app.hwConnected && strlength(current) > 0
                    app.AvailablePortsDropDown.Items = current;
                    app.AvailablePortsDropDown.Value = current;

                    % Return to connected state (green)
                    app.setStatus("ok","Still connected (no ports detected during scan)");
                else
                    app.AvailablePortsDropDown.Items = "";
                    app.AvailablePortsDropDown.Value = "";
                    app.setStatus("err","No serial ports found");
                end

                return;
            end

            % If connected and current port not in list, preserve it
            if app.hwConnected && strlength(current) > 0 && ~any(ports == current)
                ports = [current ports];
            end

            % Update dropdown items
            app.AvailablePortsDropDown.Items = ports;

            % Restore previous selection if possible
            if strlength(current) > 0 && any(ports == current)
                app.AvailablePortsDropDown.Value = current;
            else
                app.AvailablePortsDropDown.Value = ports(1);
            end

            % Final state after scan:
            % - Green if connected
            % - Red if not connected
            if app.hwConnected
                app.setStatus("ok","Scan complete (connected)");
            else
                app.setStatus("err","Scan complete (not connected)");
            end
        end

        function setStatus(app, state, msg)
            % state: "ok" | "warn" | "err" | "test"
            % Updates status text and lamp color

            app.StatusNotconnectedLabel.Text = "Status: " + string(msg);

            switch lower(string(state))
                case "ok"
                    app.StatusLamp.Color = [0.2 0.8 0.2];     % Green (connected / success)

                case "warn"
                    app.StatusLamp.Color = [0.95 0.75 0.2];   % Yellow (scanning / connecting)

                case "test"
                    app.StatusLamp.Color = [0.2 0.5 0.95];    % Blue (testing)

                otherwise
                    app.StatusLamp.Color = [0.9 0.3 0.3];     % Red (disconnected / error)
            end
        end

        function connectArduino(app)

            app.stopNow();  % Ensure motor stopped

            port  = string(app.AvailablePortsDropDown.Value);
            board = string(app.BoardsDropDown.Value);

            if strlength(port) == 0
                app.setStatus("err","Select a port first");
                return;
            end

            % Disable UI during connection attempt
            app.ConnectButton.Enable      = "off";
            app.DisconnectButton.Enable   = "off";
            app.RefreshButton.Enable      = "off";
            app.TestBlinkD13Button.Enable = "off";

            % Show connecting state (yellow)
            app.setStatus("warn","Connecting to " + port + " (" + board + ") ...");
            drawnow;

            try
                app.COM   = char(port);
                app.BOARD = char(board);

                % ------------------------------------------------------------
                % FAST FAIL: Quick COM probe (catches busy/unavailable port fast)
                % ------------------------------------------------------------
                try
                    tmp = serialport(app.COM, 115200, "Timeout", 0.5);
                    pause(0.05);
                    delete(tmp);
                catch
                    app.setStatus("err","Port cannot be opened (busy/unavailable)");
                    uialert(app.UIFigure, ...
                        "Cannot open the selected COM port." + newline + ...
                        "Possible causes:" + newline + ...
                        "- Port is already in use" + newline + ...
                        "- Driver issue" + newline + ...
                        "- Wrong COM selected", ...
                        "Connection Error");
                    app.afterConnectErrorRefresh();
                    return;
                end

                % ------------------------------------------------------------
                % Real Arduino connection
                % ------------------------------------------------------------
                app.a = arduino(app.COM, app.BOARD, "Libraries","rotaryEncoder");

                % Configure motor pins
                configurePin(app.a, app.ENA, "PWM");
                configurePin(app.a, app.IN1, "DigitalOutput");
                configurePin(app.a, app.IN2, "DigitalOutput");

                % Encoder object
                app.enc = rotaryEncoder(app.a, app.ENC_A, app.ENC_B, round(app.CPR_TOTAL));
                app.lastCount = readCount(app.enc);

                app.hwConnected = true;
                app.applyMotorPWM(0);

                % Connected = green
                app.setStatus("ok","Connected: " + port + " (" + board + ")");

                % Enable UI
                app.ConnectButton.Enable      = "off";
                app.DisconnectButton.Enable   = "on";
                app.RefreshButton.Enable      = "on";
                app.TestBlinkD13Button.Enable = "on";
                app.RunButton.Enable          = "on";
                app.StopButton.Enable         = "off";

            catch ME

                % Reset state
                app.hwConnected = false;
                app.a   = [];
                app.enc = [];

                app.setStatus("err","Connection failed");

                % Restore UI (disconnected)
                app.ConnectButton.Enable      = "on";
                app.DisconnectButton.Enable   = "off";
                app.RefreshButton.Enable      = "on";
                app.TestBlinkD13Button.Enable = "off";
                app.RunButton.Enable          = "off";
                app.StopButton.Enable         = "off";

                % Show error
                uialert(app.UIFigure, ...
                    "Unable to connect to the selected board." + newline + ...
                    "MATLAB says: " + string(ME.message) + newline + newline + ...
                    "Try:" + newline + ...
                    "- Select the correct board" + newline + ...
                    "- Select the correct COM port" + newline + ...
                    "- Unplug/plug the Arduino", ...
                    "Connection Error");

                % Soft refresh after showing the error
                app.afterConnectErrorRefresh();
            end
        end

        function disconnectArduino(app)
            app.stopNow();  % Ensure motor is stopped

            % Turn motor off before disconnecting hardware
            try
                app.applyMotorPWM(0);
            catch
            end

            app.hwConnected = false;
            app.enc = [];
            app.a   = [];

            % UI state
            app.ConnectButton.Enable      = "on";
            app.DisconnectButton.Enable   = "off";
            app.TestBlinkD13Button.Enable = "off";

            app.RunButton.Enable  = "off";
            app.StopButton.Enable = "off";

            % Refresh ports after disconnect:
            % Yellow during scan, then Red after scan (because hwConnected=false)
            app.refreshPorts();
        end

        function testConnection(app)
            if ~app.hwConnected || isempty(app.a)
                app.setStatus("err","Not connected");
                return;
            end

            % Blue during test
            app.setStatus("test","Testing connection (blink D13) ...");
            drawnow;

            try
                configurePin(app.a,"D13","DigitalOutput");
                writeDigitalPin(app.a,"D13",1); pause(1);
                writeDigitalPin(app.a,"D13",0);

                % Back to green after successful test
                app.setStatus("ok","Test OK (blinked D13)");
            catch ME
                % Red on failure
                app.setStatus("err","Test failed");
                uialert(app.UIFigure, ME.message, "Test Failed");
            end
        end


        function afterConnectErrorRefresh(app)
            % Soft refresh after a connection error:
            % - stop everything
            % - clear hardware
            % - restore UI to disconnected state
            % - rescan ports (yellow during scan, ends red if not connected)

            app.stopNow();

            app.hwConnected = false;
            app.enc = [];
            app.a   = [];

            app.ConnectButton.Enable      = "on";
            app.DisconnectButton.Enable   = "off";
            app.TestBlinkD13Button.Enable = "off";
            app.RefreshButton.Enable      = "on";

            app.RunButton.Enable  = "off";
            app.StopButton.Enable = "off";

            app.refreshPorts();
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            app.TsTarget = app.SAMPLE_MS/1000;

            % Derived CPR
            if app.RPM_AT_OUTPUT
                app.CPR_TOTAL = app.CPR_MOTOR_4X * app.GEAR_RATIO;
            else
                app.CPR_TOTAL = app.CPR_MOTOR_4X;
            end

            % Read initial UI values
            app.SETPOINT_RPM = app.RPM_TargetKnob.Value;
            app.forwardDir   = strcmpi(app.DirectionDropDown.Value,"Forward");

            % Start NOT connected
            app.hwConnected = false;
            app.a   = [];
            app.enc = [];
            app.lastCount = 0;

            % Create timer (but don't start)
            app.tmr = timer( ...
                "ExecutionMode","fixedRate", ...
                "Period", app.TsTarget, ...
                "BusyMode","drop", ...
                "TimerFcn", @(~,~) app.controlStep() );

            % Initialize Connection tab UI
            app.initConnectionTab();

            % Disable motor controls until connected
            app.RunButton.Enable  = "off";
            app.StopButton.Enable = "off";
        end

        % Value changed function: RPM_TargetKnob
        function RPM_TargetKnobValueChanged(app, event)
            app.SETPOINT_RPM = app.RPM_TargetKnob.Value;

            if app.SETPOINT_RPM < 0.5
                app.integral = 0;
                app.prevError = 0;
                app.dFiltered = 0;
            end
        end

        % Value changed function: DirectionDropDown
        function DirectionDropDownValueChanged(app, event)
            app.forwardDir = strcmpi(app.DirectionDropDown.Value, "Forward");

            if app.Running
                app.applyMotorPWM(0);
                pause(0.05);
            end
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            if ~app.hwConnected
                fprintf(2,"[WARN] Cannot run: Arduino not connected.\n");
                return;
            end

            if app.Running
                return;
            end

            app.stopFlag = false;
            app.Running = true;

            % Reset timing
            app.t0 = tic;
            app.tPrev = toc(app.t0);

            % Reset PID states
            app.firstSample = true;
            app.integral = 0;
            app.prevError = 0;
            app.dFiltered = 0;
            app.rpmFilt = 0;

            % Reset encoder baseline
            % Reset encoder baseline (only if connected)
            if app.hwConnected && ~isempty(app.enc)
                app.lastCount = readCount(app.enc);
            else
                app.lastCount = 0;
            end
            % UI enable/disable
            app.RunButton.Enable = "off";
            app.StopButton.Enable = "on";

            start(app.tmr);
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            app.stopNow();
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.stopNow();

            % Clean timer
            if ~isempty(app.tmr) && isvalid(app.tmr)
                try
                    delete(app.tmr);
                catch
                end
            end

            delete(app.UIFigure);
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            app.connectArduino();
        end

        % Button pushed function: DisconnectButton
        function DisconnectButtonPushed(app, event)
            app.disconnectArduino();
        end

        % Button pushed function: TestBlinkD13Button
        function TestBlinkD13ButtonPushed(app, event)
            app.testConnection();
        end

        % Button pushed function: RefreshButton
        function RefreshButtonPushed(app, event)
            app.refreshPorts();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ConnectionTab = uitab(app.TabGroup);
            app.ConnectionTab.Title = 'Connection';
            app.StatusPanel = uipanel(app.ConnectionTab);
            app.StatusPanel.Title = 'Status';
            app.StatusNotconnectedLabel = uilabel(app.StatusPanel);
            app.StatusNotconnectedLabel.Text = 'Status: Not connected';
            app.StatusLampLabel = uilabel(app.StatusPanel);
            app.StatusLampLabel.Text = 'Status Lamp';
            app.StatusLamp = uilamp(app.StatusPanel);
            app.ConnectionPanel = uipanel(app.ConnectionTab);
            app.ConnectionPanel.Title = 'Connection Panel';
            app.ConnectButton = uibutton(app.ConnectionPanel, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.DisconnectButton = uibutton(app.ConnectionPanel, 'push');
            app.DisconnectButton.ButtonPushedFcn = createCallbackFcn(app, @DisconnectButtonPushed, true);
            app.DisconnectButton.Text = 'Disconnect';
            app.TestingPanel = uipanel(app.ConnectionTab);
            app.TestingPanel.Title = 'Testing Panel';
            app.RefreshButton = uibutton(app.TestingPanel, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
            app.RefreshButton.Text = 'Refresh';
            app.TestBlinkD13Button = uibutton(app.TestingPanel, 'push');
            app.TestBlinkD13Button.ButtonPushedFcn = createCallbackFcn(app, @TestBlinkD13ButtonPushed, true);
            app.TestBlinkD13Button.Text = 'Test (Blink D13)';
            app.ConfigrationPanel = uipanel(app.ConnectionTab);
            app.ConfigrationPanel.Title = 'Configration Panel';
            app.SelectYourPortLabel = uilabel(app.ConfigrationPanel);
            app.SelectYourPortLabel.Text = 'Select Your Port:';
            app.SelectYourBoardLabel = uilabel(app.ConfigrationPanel);
            app.SelectYourBoardLabel.Text = 'Select Your Board:';
            app.AvailablePortsDropDownLabel = uilabel(app.ConfigrationPanel);
            app.AvailablePortsDropDownLabel.Text = 'Available Ports:';
            app.AvailablePortsDropDown = uidropdown(app.ConfigrationPanel);
            app.AvailablePortsDropDown.Items = {'COM7', 'COM8', 'COM5', '/dev/ttyUSB0'};
            app.AvailablePortsDropDown.Editable = 'on';
            app.AvailablePortsDropDown.Value = 'COM5';
            app.BoardLabel = uilabel(app.ConfigrationPanel);
            app.BoardLabel.Text = 'Boards';
            app.BoardsDropDown = uidropdown(app.ConfigrationPanel);
            app.BoardsDropDown.Items = {'Nano3', 'Uno'};
            app.BoardsDropDown.Editable = 'on';
            app.BoardsDropDown.Value = 'Nano3';
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Tab';
            app.MotorControlTab = uitab(app.TabGroup);
            app.MotorControlTab.Title = 'Motor Control';
            app.RunButton = uibutton(app.MotorControlTab, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.StopButton = uibutton(app.MotorControlTab, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.RPM_TargetKnobLabel = uilabel(app.MotorControlTab);
            app.RPM_TargetKnobLabel.Text = 'RPM_Target';
            app.RPM_TargetKnob = uiknob(app.MotorControlTab, 'continuous');
            app.RPM_TargetKnob.Limits = [0 80];
            app.RPM_TargetKnob.ValueChangedFcn = createCallbackFcn(app, @RPM_TargetKnobValueChanged, true);
            app.DirectionDropDownLabel = uilabel(app.MotorControlTab);
            app.DirectionDropDownLabel.Text = 'Direction';
            app.DirectionDropDown = uidropdown(app.MotorControlTab);
            app.DirectionDropDown.Items = {'Forward', 'Reverse'};
            app.DirectionDropDown.ValueChangedFcn = createCallbackFcn(app, @DirectionDropDownValueChanged, true);
            app.DirectionDropDown.Value = 'Forward';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PID_dc_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end